classdef CompatibilityApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        recyclingcompatibilityUIFigure  matlab.ui.Figure
        UITable                         matlab.ui.control.Table
        L_Name                          matlab.ui.control.Label
        checkmaterialgroupLabel         matlab.ui.control.Label
        DD_Check                        matlab.ui.control.DropDown
        L_Material                      matlab.ui.control.Label
    end

    
    properties (Access = private)
        com 
        mainApp        
    end
    
    methods (Access = private)
        
        function advise = getAdvise(~,compatibility)
            if compatibility < 0.9
                advise = "enable easy separation";
            elseif isnan(compatibility)
                advise = "no compatibility info available";
            else
                advise = "separation might not be necessary";
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, obj)
            app.com = obj;
            app.mainApp = mainApp;
            app.L_Name.Text = obj.name;
            app.L_Material.Text = obj.materialGroup;
            app.DD_Check.Items = mainApp.materialGroupList;
            app.DD_Check.Value = obj.materialGroup;
            
            component = strings(length(obj.compatibility),1);
            materialGroup = strings(length(obj.compatibility),1);
            compatibility = zeros(length(obj.compatibility),1);
            advise = strings(length(obj.compatibility),1);
            
            
            for i=1:length(obj.compatibility)
                component(i) = obj.compatibility(i).component.name;
                materialGroup(i) = obj.compatibility(i).component.materialGroup;
                compatibility(i) = obj.compatibility(i).value;
                advise(i) = app.getAdvise(obj.compatibility(i).value);
            end
            
            compatibilityTable = table(component, materialGroup, compatibility,advise);
            app.UITable.Data = compatibilityTable;            
            
        end

        % Value changed function: DD_Check
        function DD_CheckValueChanged(app, event)
            materialGroup = app.DD_Check.Value;                       
            row = app.mainApp.compatibility(app.mainApp.compatibility.materialGroup == string(materialGroup),:);
            
            component = strings(length(app.com.compatibility),1);
            materialGroup = strings(length(app.com.compatibility),1);
            compatibility = zeros(length(app.com.compatibility),1);
            advise = strings(length(app.com.compatibility),1);
            
            for i = 1:length(app.com.compatibility)
                component(i) = app.com.compatibility(i).component.name;
                materialGroup(i) = app.com.compatibility(i).component.materialGroup;
                compatibility(i) = row.(materialGroup(i));
                advise(i) = app.getAdvise(compatibility(i));
            end
            
            compatibilityTable = table(component, materialGroup, compatibility,advise);
            app.UITable.Data = compatibilityTable;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.recyclingcompatibilityUIFigure = uifigure('Visible', 'off');
            app.recyclingcompatibilityUIFigure.Name = 'recycling compatibility';
            app.UITable = uitable(app.recyclingcompatibilityUIFigure);
            app.UITable.ColumnName = {'component'; 'material group'; 'compatibility'; 'advise'};
            app.UITable.RowName = {};
            app.L_Name = uilabel(app.recyclingcompatibilityUIFigure);
            app.checkmaterialgroupLabel = uilabel(app.recyclingcompatibilityUIFigure);
            app.checkmaterialgroupLabel.Text = 'check material group';
            app.DD_Check = uidropdown(app.recyclingcompatibilityUIFigure);
            app.DD_Check.ValueChangedFcn = createCallbackFcn(app, @DD_CheckValueChanged, true);
            app.L_Material = uilabel(app.recyclingcompatibilityUIFigure);
            % Show the figure after all components are created
            app.recyclingcompatibilityUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CompatibilityApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.recyclingcompatibilityUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.recyclingcompatibilityUIFigure)
        end
    end
end