classdef EcoSearchApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ecoinventsearchUIFigure  matlab.ui.Figure
        flowLabel                matlab.ui.control.Label
        E_Search                 matlab.ui.control.EditField
        BTN_search               matlab.ui.control.Button
        UITable                  matlab.ui.control.Table
        BTN_apply                matlab.ui.control.Button
        BTN_cancel               matlab.ui.control.Button
    end

    
    properties (Access = private)
        nextFnc % Description
        mainApp
        type %intermediate or elementary
        selection
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, nextFnc, type)
            app.nextFnc = nextFnc;
            app.mainApp = mainApp;
            app.type = type;
        end

        % Button pushed function: BTN_search
        function BTN_searchButtonPushed(app, event)
            if app.type == "intermediate"
                app.UITable.ColumnName = app.mainApp.ie.Properties.VariableNames;
                app.UITable.Data = app.mainApp.ie(contains(string(app.mainApp.ie.product),app.E_Search.Value),:);
            else
                app.UITable.ColumnName = app.mainApp.ee.Properties.VariableNames;
                app.UITable.Data = app.mainApp.ee(contains(string(app.mainApp.ee.name),app.E_Search.Value),:);
            end
        end

        % Button pushed function: BTN_cancel
        function BTN_cancelButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: BTN_apply
        function BTN_applyButtonPushed(app, event)
            app.nextFnc(app.UITable.Data(app.selection(1),:));                        
            delete(app);
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
           app.selection = event.Indices(1);
        end

        % Value changed function: E_Search
        function E_SearchValueChanged(app, event)
            app.BTN_searchButtonPushed();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ecoinventsearchUIFigure = uifigure('Visible', 'off');
            app.ecoinventsearchUIFigure.Name = 'ecoinvent search';
            app.flowLabel = uilabel(app.ecoinventsearchUIFigure);
            app.flowLabel.Text = 'flow';
            app.E_Search = uieditfield(app.ecoinventsearchUIFigure, 'text');
            app.E_Search.ValueChangedFcn = createCallbackFcn(app, @E_SearchValueChanged, true);
            app.BTN_search = uibutton(app.ecoinventsearchUIFigure, 'push');
            app.BTN_search.ButtonPushedFcn = createCallbackFcn(app, @BTN_searchButtonPushed, true);
            app.BTN_search.Text = 'serach';
            app.UITable = uitable(app.ecoinventsearchUIFigure);
            app.UITable.ColumnName = {''};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.BTN_apply = uibutton(app.ecoinventsearchUIFigure, 'push');
            app.BTN_apply.ButtonPushedFcn = createCallbackFcn(app, @BTN_applyButtonPushed, true);
            app.BTN_apply.Text = 'apply';
            app.BTN_cancel = uibutton(app.ecoinventsearchUIFigure, 'push');
            app.BTN_cancel.ButtonPushedFcn = createCallbackFcn(app, @BTN_cancelButtonPushed, true);
            app.BTN_cancel.Text = 'cancel';
            % Show the figure after all components are created
            app.ecoinventsearchUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EcoSearchApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ecoinventsearchUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ecoinventsearchUIFigure)
        end
    end
end