classdef HybridProcessSelectionApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HybridProcessSelectionAppUIFigure  matlab.ui.Figure
        hybridprocessesListBoxLabel  matlab.ui.control.Label
        hybridprocessesListBox       matlab.ui.control.ListBox
        createnewButton              matlab.ui.control.Button
        editButton                   matlab.ui.control.Button
        applyButton                  matlab.ui.control.Button
        cancel                       matlab.ui.control.Button
        deleteButton                 matlab.ui.control.Button
    end

    
    properties (Access = private)
        mainApp % Description
        hprocessStack HybridProcess
        operation        
    end
    
    methods (Access = private)
        
        function updateStack(app,process)
            replaced = false;
            for i = 1:length(app.hprocessStack)
                if app.hprocessStack(i).name == process.name
                    app.hprocessStack(i) = process;
                    replaced = true;
                    break
                end
            end
            if ~replaced
                app.hprocessStack(end+1) = process;
        end
        stack = app.hprocessStack;
        save("hprocessStack","stack");
        app.displayStack();
        end
        
        function displayStack(app)
            processNames = strings(length(app.hprocessStack),1);
            for i = 1:length(app.hprocessStack)
                processNames(i) = app.hprocessStack(i).name;                
            end
            app.hybridprocessesListBox.Items = processNames;
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.mainApp = mainApp;           
            load hprocessStack
            app.hprocessStack = stack;
            app.displayStack();
            app.operation = app.mainApp.activeElement.stages(app.mainApp.stageId).operations(app.mainApp.operationId);
        end

        % Button pushed function: createnewButton
        function createnewButtonPushed(app, event)
            emptyProcess = HybridProcess.empty();
            HybridApp(app.mainApp,emptyProcess,@app.updateStack);
        end

        % Button pushed function: editButton
        function editButtonPushed(app, event)
            hprocess = app.hprocessStack(string(app.hybridprocessesListBox.Items) == string(app.hybridprocessesListBox.Value));
            HybridApp(app.mainApp,hprocess,@app.updateStack);
        end

        % Button pushed function: deleteButton
        function deleteButtonPushed(app, event)
            app.hprocessStack(string(app.hybridprocessesListBox.Items) == string(app.hybridprocessesListBox.Value)) = [];
            stack = app.hprocessStack;
            save("hprocessStack","stack");
            app.displayStack();
        end

        % Button pushed function: cancel
        function cancelButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: applyButton
        function applyButtonPushed(app, event)
            selectedProcess = app.hprocessStack(string(app.hybridprocessesListBox.Items) == string(app.hybridprocessesListBox.Value));
            selectedProcess.calculateLCIA(app.mainApp);
            app.operation.addProcess(selectedProcess);
            app.operation.displayOperation(app.mainApp);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HybridProcessSelectionAppUIFigure = uifigure('Visible', 'off');
            app.HybridProcessSelectionAppUIFigure.Name = 'HybridProcessSelectionApp';
            app.hybridprocessesListBoxLabel = uilabel(app.HybridProcessSelectionAppUIFigure);
            app.hybridprocessesListBoxLabel.Text = 'hybrid processes';
            app.hybridprocessesListBox = uilistbox(app.HybridProcessSelectionAppUIFigure);
            app.createnewButton = uibutton(app.HybridProcessSelectionAppUIFigure, 'push');
            app.createnewButton.ButtonPushedFcn = createCallbackFcn(app, @createnewButtonPushed, true);
            app.createnewButton.Text = 'create new';
            app.editButton = uibutton(app.HybridProcessSelectionAppUIFigure, 'push');
            app.editButton.ButtonPushedFcn = createCallbackFcn(app, @editButtonPushed, true);
            app.editButton.Text = 'edit';
            app.applyButton = uibutton(app.HybridProcessSelectionAppUIFigure, 'push');
            app.applyButton.ButtonPushedFcn = createCallbackFcn(app, @applyButtonPushed, true);
            app.applyButton.Text = 'apply';
            app.cancel = uibutton(app.HybridProcessSelectionAppUIFigure, 'push');
            app.cancel.ButtonPushedFcn = createCallbackFcn(app, @cancelButtonPushed, true);
            app.cancel.Text = 'cancel';
            app.deleteButton = uibutton(app.HybridProcessSelectionAppUIFigure, 'push');
            app.deleteButton.ButtonPushedFcn = createCallbackFcn(app, @deleteButtonPushed, true);
            app.deleteButton.Text = 'delete';
            % Show the figure after all components are created
            app.HybridProcessSelectionAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HybridProcessSelectionApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HybridProcessSelectionAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HybridProcessSelectionAppUIFigure)
        end
    end
end