classdef LCD < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LifeCycleDesignerUIFigure  matlab.ui.Figure
        fileMenu                   matlab.ui.container.Menu
        newMenu                    matlab.ui.container.Menu
        importMenu                 matlab.ui.container.Menu
        importfromxlsxMenu         matlab.ui.container.Menu
        saveMenu                   matlab.ui.container.Menu
        openMenu                   matlab.ui.container.Menu
        mappingMenu                matlab.ui.container.Menu
        mappingeditorMenu          matlab.ui.container.Menu
        settingsMenu               matlab.ui.container.Menu
        loadMatrixMenu             matlab.ui.container.Menu
        saveengineMenu             matlab.ui.container.Menu
        loadengineMenu             matlab.ui.container.Menu
        optionsMenu                matlab.ui.container.Menu
        versionsMenu               matlab.ui.container.Menu
        createversionMenu          matlab.ui.container.Menu
        selectversionMenu          matlab.ui.container.Menu
        standardMenu               matlab.ui.container.Menu
        removeversionMenu          matlab.ui.container.Menu
        compareversionsMenu        matlab.ui.container.Menu
        debugMenu                  matlab.ui.container.Menu
        exportappMenu              matlab.ui.container.Menu
        clearprocessengineMenu     matlab.ui.container.Menu
        saveplotaspdfMenu          matlab.ui.container.Menu
        exportMenu                 matlab.ui.container.Menu
        resulttableMenu            matlab.ui.container.Menu
        GridLayout                 matlab.ui.container.GridLayout
        Btn_LifeLink               matlab.ui.control.StateButton
        CB_ShowFeatures            matlab.ui.control.CheckBox
        TV_Components              matlab.ui.container.Tree
        analysisPanel              matlab.ui.container.Panel
        L_Indicator                matlab.ui.control.Label
        indicatorDropDownLabel     matlab.ui.control.Label
        DD_Indicator               matlab.ui.control.DropDown
        categoryLabel              matlab.ui.control.Label
        DD_Category                matlab.ui.control.DropDown
        L_LciaMethod               matlab.ui.control.Label
        BTN_Network                matlab.ui.control.Button
        BTN_Reference              matlab.ui.control.Button
        BTN_Compare                matlab.ui.control.Button
        L_Selection                matlab.ui.control.Label
        BTN_SensitivityAnalysis    matlab.ui.control.Button
        BTN_LCIA                   matlab.ui.control.Button
        BTN_Time                   matlab.ui.control.Button
        BTN_AnalyseAsm             matlab.ui.control.Button
        P_Settings_Process         matlab.ui.container.Panel
        L_functionalunit           matlab.ui.control.Label
        L_availableProcesses       matlab.ui.control.Label
        DD_alternativeProcesses    matlab.ui.control.DropDown
        BTN_updateProcess          matlab.ui.control.Button
        E_Correction               matlab.ui.control.NumericEditField
        L_Correction               matlab.ui.control.Label
        L_availableLocations       matlab.ui.control.Label
        DD_availableLocations      matlab.ui.control.DropDown
        L_Quantity                 matlab.ui.control.Label
        E_Quantity                 matlab.ui.control.EditField
        L_Navi                     matlab.ui.control.Label
        BTN_Load                   matlab.ui.control.Button
        L_Mode                     matlab.ui.control.Label
        BTN_Open                   matlab.ui.control.Button
        BTN_Close                  matlab.ui.control.Button
        L_RefTime                  matlab.ui.control.Label
        BTN_Update                 matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
        L_Version                  matlab.ui.control.Label
        P_Settings_Stage           matlab.ui.container.Panel
        BTN_addOperation           matlab.ui.control.Button
        BTN_removeOperation        matlab.ui.control.Button
        P_Settings_Operation       matlab.ui.container.Panel
        BTN_Exchange               matlab.ui.control.Button
        BTN_Add_Eco                matlab.ui.control.Button
        BTN_Remove                 matlab.ui.control.Button
        BTN_Add_Hybrid             matlab.ui.control.Button
        P_Settings_Assembly        matlab.ui.container.Panel
        CB_IgnoreAssembly          matlab.ui.control.CheckBox
        BTN_LoopsAssembly          matlab.ui.control.Button
        BTN_updateAssembly         matlab.ui.control.Button
        lifespanyrLabel_2          matlab.ui.control.Label
        S_LifespanAssembly         matlab.ui.control.Spinner
        ExchangableAssembly        matlab.ui.control.CheckBox
        P_Settings_Component       matlab.ui.container.Panel
        materialDropDownLabel      matlab.ui.control.Label
        DD_Material                matlab.ui.control.DropDown
        classificationLabel        matlab.ui.control.Label
        DD_Classification          matlab.ui.control.DropDown
        masskgEditFieldLabel       matlab.ui.control.Label
        E_Mass                     matlab.ui.control.NumericEditField
        volumemLabel               matlab.ui.control.Label
        E_Volume                   matlab.ui.control.NumericEditField
        BTN_Loops                  matlab.ui.control.Button
        BTN_updateComponent        matlab.ui.control.Button
        CB_IgnoreComponent         matlab.ui.control.CheckBox
        lifespanyrLabel            matlab.ui.control.Label
        S_LifespanComponent        matlab.ui.control.Spinner
        BTN_EditParameter          matlab.ui.control.Button
        CB_ExchangableComponent    matlab.ui.control.CheckBox
        BTN_Compatibility          matlab.ui.control.Button
        groupLabel                 matlab.ui.control.Label
        DD_MaterialGroup           matlab.ui.control.DropDown
        BTN_Explore                matlab.ui.control.Button
        L_Compatibility            matlab.ui.control.Label
        quantityLabel              matlab.ui.control.Label
        S_quantity                 matlab.ui.control.Spinner
        P_Settings_HyProcess       matlab.ui.container.Panel
        L_Hybrid_Functionalunit    matlab.ui.control.Label
        E_Hybrid_Quantity          matlab.ui.control.EditField
        BTN_updateHybridProcess    matlab.ui.control.Button
        L_Hybrid_Quantity          matlab.ui.control.Label
        CM_tree                    matlab.ui.container.ContextMenu
        deleteMenu                 matlab.ui.container.Menu
        addMenu                    matlab.ui.container.Menu
        assemblyMenu               matlab.ui.container.Menu
        componentMenu              matlab.ui.container.Menu
    end

    
    properties (Access = private)
        mappingApp %Mapping UI
        processesApp %ProcessEditor
        
        %fileName = 'C:\Users\Z420\Desktop\exchange_folder\F-BOM.xml'; %Xml Dir
        I_asm %Import Model Structure
        
        time timer
        
    end
    
    properties (Access = public)
        
        iteration = 'none';
        
        activeVersion = 'standard';
        
        alternatives;
        
        activeElement %selected component in TreeView
        stageId = 0 %selected stage
        operationId = 0 %selected operation
        processId = 0; %selected process
        classificationType %used for classification updates, set when clicking on stages
        %classificationTypes = ["production","assembly","use","eol"] %lists all possible classificationTypes
        
        stageNames % e.g. recycled material
        stageIDs % e.g. recycledMaterial
        stageClassificationTypes % e.g. production
        
        classificationTables
        conditionTables
        asmStageId = 0 %selected stage in assembly mode
        
        model = LCModel('mainModel');
        modelImpact
        
        referenceModel
        impactsReference
        
        mode = "none" %can be 'Assembly' 'Stage Results' 'Component' 'Stage' 'Process' 'Sensitivity'
        A_inv
        A_min
        B
        LCIA
        Q
        C
        ie
        ee
        processEngine ProcessEngine %handles creation and use of processess
        
        %material properties
        materialProperties
        materialGroupProperties
        recyclingRates
        compatibility
        
        
        %configList
        
        configTable
        altProcessesTable
        materialGroupList
        classificationList
        
        lciaMethod string
        lciaIndicator string
        lciaIndex double % identifies impact for selected indicator
        lciaUnit string
        
        options %showObsoleteMethods, lciaMethod, displayFlows, allowance
        
        projectLoaded = false;


    end
    
    methods (Access = public)
        
        function listMaterials(app, materialGroup)
            MP = app.materialProperties;
            app.DD_Material.Items = cellstr(MP.Material(MP.Group == string(materialGroup)));
        end
        
        
        function setTVSelection(app)
            app.TV_Components.SelectedNodes = app.TV_Components.Children; %select first component
            app.TV_ComponentsSelectionChanged();
        end
        
        function [coordinateSelected, minIdx] = getCoord(app, hObj, event)
            x = hObj.XData;
            y = hObj.YData;
            z = hObj.ZData;
            pt = event.IntersectionPoint;
            coordinates = [x(:),y(:),z(:)];
            dist = pdist2(pt,coordinates);
            [~, minIdx] = min(dist);
            coordinateSelected = coordinates(minIdx,:);
            persistent chk;
            if isempty(chk)
                chk = 1;
                pause(0.2); %Add a delay to distinguish single click from a double click
                if chk == 1
                    %single click
                    hf = get(hObj,'parent');
                    n = get(hf,'parent');
                    grid = get(n,'parent');
                    b = get(grid,'selectiontype');
                    if strcmpi(b,'normal')
                        app.SetOptions(minIdx,hObj);
                    else
                        %rightclick
                    end
                    chk = [];
                end
            else
                chk = [];
                %double click
                app.SetOptions(minIdx,hObj);
                app.openElement();
            end
        end
        
        function SetOptions(app,id,hObj)
            
            if (app.mode =="Component")
                if app.stageId > 0 %reset previous selection
                    highlight(hObj,app.stageId,'MarkerSize',30);
                end
                highlight(hObj,id,'MarkerSize',40);
                stageName = app.activeElement.stages(id).name;
                app.P_Settings_Component.Title = "settings - " + app.activeElement.name + " - "+ stageName;
                app.stageId = id;
                app.setClassificationType(stageName)
                app.BTN_updateProcess.Visible = false;
            elseif (app.mode == "Stage")
                if app.operationId > 0 %reset previous selection
                    highlight(hObj,app.operationId,'MarkerSize',30);
                end
                highlight(hObj,id,'MarkerSize',40);
                app.operationId = id;
                app.P_Settings_Stage.Title = "settings - " + app.activeElement.stages(app.stageId).operations(id).name;
                app.BTN_removeOperation.Enable = true;
            elseif (app.mode == "Operation")
                if app.processId > 0 %reset previous selection
                    highlight(hObj,app.processId,'MarkerSize',30);
                end
                app.processId = id;
                highlight(hObj,id,'MarkerSize',40);                
                app.P_Settings_Operation.Title = "settings - " + app.activeElement.stages(app.stageId).operations(app.operationId).processes(id).name;                
                app.BTN_Exchange.Enable = true;
                app.BTN_Remove.Enable = true;
            %elseif (app.mode == "Process")               
            elseif (app.mode == "Assembly")
                app.P_Settings_Assembly.Title = "settings - " + app.activeElement.name;
                if app.asmStageId > 0 %reset previous selection
                    highlight(hObj,app.asmStageId,'MarkerSize',30);
                end
                highlight(hObj,id,'MarkerSize',40);
                app.asmStageId = id;
                app.BTN_Open.Enable = true;
            end
        end
        
        function listCategories(app)
            lciaTableMethods = app.LCIA(app.LCIA.method == app.lciaMethod,:);
            app.L_LciaMethod.Text = 'method: ' + app.lciaMethod;
            categories = unique(lciaTableMethods.category);
            app.DD_Category.Items = categories;
            app.listIndicators();
        end
        
        function impact = getModelImpact(app)
            impact = app.model.root.generateLCIA(app);
            app.modelImpact = impact;
        end
        
    end
    
    methods (Access = private)
        
        function setClassificationType(app,stageName)
            if ~isempty(stageName)%
                app.classificationType = app.stageClassificationTypes(app.stageIDs == stageName);
                possibleClassifications = app.classificationList.(app.classificationType){app.materialGroupList == app.activeElement.materialGroup};
                app.DD_Classification.Items = possibleClassifications;
                app.DD_Classification.Value = string(app.activeElement.classification.(app.classificationType));
                app.DD_Classification.Enable = true;
            else
                app.DD_Classification.Items = cell(0);
                app.DD_Classification.Enable = false;
            end
        end
        
        function openElement(app)
            if app.mode == "Component" && app.stageId > 0
                app.activeElement.stages(app.stageId).plot(app);
                app.mode = "Stage";
                app.BTN_Open.Text = "open operation";
                app.BTN_Close.Text = "close stage";
                app.BTN_Close.Enable = true;
                app.operationId = 0;
                app.E_Quantity.Visible = false;
                app.L_Quantity.Visible = false;
                app.L_functionalunit.Visible = false;
                app.P_Settings_Stage.Title = "settings";
                app.BTN_Network.Enable = false;
                app.BTN_removeOperation.Enable = false;
            elseif app.mode == "Stage" && app.operationId > 0
                app.BTN_Open.Text = "open process";
                app.BTN_Close.Text = "close operation";
                op = app.activeElement.stages(app.stageId).operations(app.operationId);
                op.plot(app);
                app.mode = "Operation";
                app.processId = 0;
                app.P_Settings_Operation.Title = "settings";
                app.showProcessEdits();
            elseif app.mode == "Operation" && app.processId > 0
                obj = app.activeElement.stages(app.stageId).operations(app.operationId).processes(app.processId);
                obj.plot(app,app.UIAxes);
                if app.options.normTime
                    app.L_Navi.Text = app.activeElement.name + ' / ' + obj.parent.parent.name + ' / ' + obj.parent.name + ' / ' + obj.name + " (" + string(round(obj.generateLCIA(app),2)) +" "+ app.lciaUnit + " / yr)";
                else
                    app.L_Navi.Text = app.activeElement.name + ' / ' + obj.parent.parent.name + ' / ' + obj.parent.name + ' / ' + obj.name + " (" + string(round(obj.generateLCIA(app),2)) +" "+ app.lciaUnit + ")";
                end                
                
                app.BTN_Open.Text = "open";
                app.BTN_Open.Enable = false;
                app.BTN_Close.Text = "close process";                               
                
                param = string(fieldnames(app.activeElement.processParameter));
                param = [param; string(fieldnames(app.activeElement.customParameter))];
                
                if isa(obj,"EcoinventProcess")  
                    app.mode = "EcoProcess";   
                    app.P_Settings_Process.Title = "settings - " + obj.name;                    
                    app.E_Quantity.Tooltip = param;                                        
                    app.L_functionalunit.Text = string("functional unit [" + string(obj.unit) + "]");
                    app.enableProcessEdits();
                    app.L_Quantity.Text = string(obj.quantity);
                    app.E_Quantity.Value = obj.quantityExpression;
                    app.E_Correction.Value = obj.correction;
                    app.DD_availableLocations.Items = obj.locList;
                    app.DD_availableLocations.Value = obj.activityLoc;     
                    alternativesCell = string(length(obj.alternativeProcesses));
                    for a = 1:length(obj.alternativeProcesses)
                        alternativesCell(a) = obj.alternativeProcesses(a).name;
                    end
                    app.DD_alternativeProcesses.Items = sort(alternativesCell);
                    app.DD_alternativeProcesses.Value = obj.name;      
                else %Hybrid Process
                    app.mode = "HybridProcess";   
                    app.P_Settings_HyProcess.Title = "settings - " + obj.name;                   
                    app.E_Hybrid_Quantity.Tooltip = param;
                    app.L_functionalunit.Text = string("functional unit [" + string(obj.unit) + "]");
                    app.E_Hybrid_Quantity.Value = obj.quantityExpression;
                    app.L_Hybrid_Quantity.Text = string(obj.parseQuantity(obj.quantityExpression));
                end
                
            %elseif app.mode == "Process"
                % nothing
            elseif app.mode == "Assembly" && app.asmStageId > 0
                app.activeElement.showStageResults(app,app.asmStageId);
                app.BTN_Open.Text = "open";
                app.BTN_Open.Enable = false;
                app.BTN_Close.Text = "close stage impacts";
                app.BTN_Close.Enable = true;
                app.mode = "Stage Results";
                %app.L_Navi.Text = app.activeElement.name + " (" + string(round(app.activeElement.generateLCIA(app),2)) +" "+ app.lciaUnit + ")";
            end
            app.L_Mode.Text = lower(app.mode);
            app.displaySettings();
        end
        
        function closeElement(app)
            if app.mode == "Stage" || app.mode == "Sensitivity"
                app.BTN_Open.Text = "open stage";
                app.BTN_Close.Text = "close";
                app.BTN_Close.Enable = false;
                app.activeElement.plot(app);
                app.L_Navi.Text = app.activeElement.name + " (" + string(app.activeElement.generateLCIA(app)) +" "+ app.lciaUnit + ")";
                app.mode = "Component";
                app.E_Quantity.Visible = false;
                app.L_Quantity.Visible = false;
                app.L_functionalunit.Visible = false;
                app.P_Settings_Process.Title = "settings";
            elseif app.mode == "Operation"
                app.BTN_removeOperation.Enable = false;
                app.BTN_Open.Text = "open operation";
                app.BTN_Close.Text = "close stage";
                app.activeElement.stages(app.stageId).plot(app);
                app.L_Navi.Text = app.activeElement.name + ' / ' + app.activeElement.stages(app.stageId).name;
                app.mode = "Stage";
                app.P_Settings_Process.Title = "settings";
                app.hideProcessEdits();
            elseif app.mode == "EcoProcess" || app.mode == "HybridProcess"
                app.BTN_Open.Enable = true;
                app.BTN_Open.Text = "open process";
                app.BTN_Close.Text = "close operation";
                app.activeElement.stages(app.stageId).operations(app.operationId).plot(app);
                app.L_Navi.Text = app.activeElement.name + ' / ' + app.activeElement.stages(app.stageId).name + ' / ' + app.activeElement.stages(app.stageId).operations(app.operationId).name;
                app.mode = "Operation";
                app.E_Quantity.Enable = false;
            elseif app.mode =="Stage Results"
                app.activeElement.plot(app);
                app.mode = "Assembly";
                app.asmStageId = 0;
                app.BTN_Close.Text = "close";
                app.BTN_Close.Enable = false;
                app.BTN_Open.Text = "open stage impacts";
                app.BTN_Open.Enable = true;
            elseif app.mode == "ProcessOpen"
                app.mode = "Process";
            end
            app.L_Mode.Text = lower(app.mode);
            app.displaySettings();
        end
        
        function loadMatrix(app)
            h = waitbar(0.2,['import matrices...']);
            load('LciMat');
            waitbar(0.5,h,['load matrices...']);
            app.A_inv = A_inv;
            app.A_min= A_min;
            app.B = B;
            app.LCIA = LCIA;
            app.Q = Q;
            app.C = C;
            waitbar(0.8,h,['load matrices...']);
            app.ie = ie;
            app.ee = ee;
            close(h)
            
            uniqueClassificationTypes = unique(app.stageClassificationTypes);
            
            for c = 1:length(uniqueClassificationTypes)
                load(uniqueClassificationTypes(c)+'ClassificationList.mat') %includes material, production
                app.classificationList.(uniqueClassificationTypes(c))= classifications;
            end
            in = load('configTable.mat');
            app.configTable = in.configTable;
            app.altProcessesTable = in.altProcessesTable;
            app.processEngine = app.processEngine.loadProcesses();
            
            
        end
        
        function listIndicators(app)
            lciaTableMethods = app.LCIA(app.LCIA.method == app.lciaMethod,:);
            indicatorTableMethods = lciaTableMethods(lciaTableMethods.category == string(app.DD_Category.Value),:);
            indicators = unique(indicatorTableMethods.indicator);
            app.DD_Indicator.Items = indicators;
            app.updateLCIA();
        end
        
        function updateLCIA(app)                       
            
            app.lciaIndicator = app.DD_Indicator.Value;
            lciaTableMethods = app.LCIA(app.LCIA.method == app.lciaMethod,:);
            categoryTableMethods = lciaTableMethods(lciaTableMethods.category == string(app.DD_Category.Value),:);
            indexTable = categoryTableMethods(categoryTableMethods.indicator == app.lciaIndicator,:);
            app.lciaIndex = indexTable.index;
            app.lciaUnit = indexTable.unitName;            
            
            if app.projectLoaded                
                app.modelImpact = app.model.root.generateLCIA(app);
                switch app.mode
                    case "Assembly"
                        app.activeElement.plot(app);                        
                    case "Component"
                        app.activeElement.plot(app);
                    case "Stage"
                        app.activeElement.stages(app.stageId).plot(app);
                    case "Operation"
                        app.activeElement.stages(app.stageId).operations(app.operationId).plot(app);
                    case "Process"
                        app.activeElement.stages(app.stageId).operations(app.operationId).processes(app.processId).plot(app,app.UIAxes);
                    case "Stage Results"
                        app.activeElement.showStageResults(app,app.asmStageId);
                    case "Compare Versions"
                        compareVersions(app);
                end
                app.L_Navi.Text = app.activeElement.name + " (" + string(app.activeElement.generateLCIA(app)) +" "+ app.lciaUnit + ")";
            end
        end
        
        function showProcessEdits(app)
            app.L_Correction.Visible = true;
            app.E_Correction.Visible = true;
            app.E_Correction.Value = 0;
            app.E_Correction.Enable = false;
            app.L_availableLocations.Visible = true;
            app.DD_availableLocations.Visible = true;
            app.DD_availableLocations.Items = {};
            app.DD_availableLocations.Enable = false;
            app.E_Quantity.Enable = false;
            app.E_Quantity.Visible = true;
            app.L_Quantity.Visible = true;
            app.E_Quantity.Value = '';
            app.L_functionalunit.Visible = true;
            app.L_functionalunit.Text = "functional unit";
            app.L_availableProcesses.Visible = true;
            app.DD_alternativeProcesses.Visible = true;
            app.DD_alternativeProcesses.Items = {};
            app.DD_alternativeProcesses.Enable = false;
            app.BTN_updateProcess.Visible = true;
            app.BTN_updateProcess.Enable = false;
            app.BTN_Exchange.Enable = false;
            app.BTN_Remove.Enable = false;
        end
        
        function hideProcessEdits(app)
            app.L_Correction.Visible = false;
            app.E_Correction.Visible = false;
            app.L_availableLocations.Visible = false;
            app.DD_availableLocations.Visible = false;
            app.E_Quantity.Visible = false;
            app.L_Quantity.Visible = false;
            app.L_functionalunit.Visible = false;
            app.L_availableProcesses.Visible = false;
            app.DD_alternativeProcesses.Visible = false;
            app.BTN_updateProcess.Visible = false;
        end
        
        function enableProcessEdits(app)
            app.E_Correction.Enable = true;
            app.DD_availableLocations.Enable = true;
            app.E_Quantity.Enable = true;
            app.DD_alternativeProcesses.Enable = true;
            app.BTN_Exchange.Enable = true;
            app.BTN_Remove.Enable = true;
            app.BTN_updateProcess.Enable = true;
        end
        
        
        function displaySettings(app)
            app.P_Settings_Assembly.Visible = false;
            app.P_Settings_Component.Visible = false;
            app.P_Settings_Stage.Visible = false;
            app.P_Settings_Operation.Visible = false;
            app.P_Settings_Process.Visible = false;
            app.P_Settings_HyProcess.Visible = false;
            
            switch app.mode
                case 'Assembly'
                    app.P_Settings_Assembly.Visible = true;
                case 'Component'
                    app.P_Settings_Component.Visible = true;
                case 'Stage'
                    app.P_Settings_Stage.Visible = true;
                case 'Operation'
                    app.P_Settings_Operation.Visible = true;
                case 'EcoProcess'
                    app.P_Settings_Process.Visible = true;
                case 'HybridProcess'
                    app.P_Settings_HyProcess.Visible = true;
            end
            
        end
        
        
        function addProcess(app,pro,op)                
            op.addProcess(pro);               
            op.plot(app);
        end
        
        function exchangeProcess(app,pro,op,id)            
            op.processes(id) = pro;
            op.plot(app);
        end
        
        function value = parse(app,expression)
            a = app.activeElement.processParameter;
            
            dicNames = string(fieldnames(a));
            dicValues = zeros(length(dicNames),1);
            for i = 1:length(dicNames)
                dicValues(i) = a.(dicNames(i)).value;
            end
            
            cparameter = app.activeElement.customParameter;
            if ~isempty(cparameter)
                cdicNames = string(fieldnames(cparameter));
                cdicValues = zeros(length(cdicNames),1);
                for i = 1:length(cdicNames)
                    cdicValues(i) = cparameter.(cdicNames(i)).value;
                end
                pe = parserEngine();
                pe.dicNames = [dicNames; cdicNames];
                pe.dicValues = [dicValues; cdicValues];
            else
                pe = parserEngine();
                pe.dicNames = [dicNames];
                pe.dicValues = [dicValues];
            end
            value = pe.run(expression);
        end
        
        function removeVersion(app, event)                                    
            app.alternatives = rmfield(app.alternatives,event.Source.Text);
            if app.activeVersion == string(event.Source.Text)                
                app.model.root = app.alternatives.standard;
                app.activeElement = app.model.root;        
                app.setTVSelection();
                app.activeElement.plot(app);
                app.L_Version.Text = "version: standard";
            end
            delete(app.selectversionMenu.Children(string(get(app.selectversionMenu.Children,'Text')) == event.Source.Text));
            delete(app.removeversionMenu.Children(string(get(app.removeversionMenu.Children,'Text')) == event.Source.Text));
         end
         
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %profile on
            
            % add settings panels to layout
            app.displaySettings();
            app.P_Settings_Assembly.Parent = app.GridLayout;
            app.P_Settings_Assembly.Layout.Row = [7 8];
            app.P_Settings_Assembly.Layout.Column = [5 6];
            app.P_Settings_Component.Parent = app.GridLayout;
            app.P_Settings_Component.Layout.Row = [7 8];
            app.P_Settings_Component.Layout.Column = [5 6];
            app.P_Settings_Stage.Parent = app.GridLayout;
            app.P_Settings_Stage.Layout.Row = [7 8];
            app.P_Settings_Stage.Layout.Column = [5 6];
            app.P_Settings_Operation.Parent = app.GridLayout;
            app.P_Settings_Operation.Layout.Row = [7 8];
            app.P_Settings_Operation.Layout.Column = [5 6];
            app.P_Settings_HyProcess.Parent = app.GridLayout;
            app.P_Settings_HyProcess.Layout.Row = [7 8];
            app.P_Settings_HyProcess.Layout.Column = [5 6];
            
            % preset options
            app.options.showObsoleteMethods = false;
            app.options.lciaMethod = 'ReCiPe Midpoint (H) V1.13';
            app.options.displayFlows = true;
            app.options.allowance = 5;
            app.options.referenceTime = 20;
            app.options.exchangeFilePath = [getenv('APPDATA') '\LCD\'];
            app.options.normTime = true;
            app.options.transient = true;
            
            % configure timer
            app.time = timer('Period',1,'ExecutionMode','fixedRate');
            app.time.TimerFcn = {@checkForUpdates,app};
            
            
            app.hideProcessEdits();
            app.processEngine = ProcessEngine;
            
            %importMenuSelected(app);
            app.processEngine = app.processEngine.loadProcesses;
            
            
            %read stages & stageClassifications
            stageTable = readtable("tables/stages.xlsx",'sheet','stages');
            app.stageNames = string(stageTable.stageName);
            app.stageIDs = string(stageTable.stageID);
            app.stageClassificationTypes = string(stageTable.classificationType);
            
            tic
            app.loadMatrix();
            toc
            
            %read material properties
            app.materialProperties = readtable("tables/materialProperties.xlsx",'sheet','values');
            app.materialGroupProperties = readtable("tables/materialGroupProperties.xlsx",'sheet','properties');
            app.recyclingRates = readtable("tables/materialGroupProperties.xlsx",'sheet','recycling');
            app.materialGroupList = [string(app.recyclingRates.materialGroup);"None"];
            app.compatibility = readtable("tables/compatibility.xlsx",'sheet','compatibility','PreserveVariableNames',true);
            
            %read classifications & conditions
            classificationTypes = unique(app.stageClassificationTypes);
            for i = 1:length(classificationTypes)
                app.classificationTables.(classificationTypes(i)) = readtable('tables/'+classificationTypes(i)+'_classification.xlsx','sheet','classification');
                app.conditionTables.(classificationTypes(i)) = readtable('tables/'+classificationTypes(i)+'_classification.xlsx','sheet','conditions');
            end
            
            
            methods = unique(app.LCIA.method);
            if ~app.options.showObsoleteMethods
                methods = methods(~contains(methods,'obsolete'),:);
            end
            app.lciaMethod = 'ReCiPe Midpoint (I) V1.13';
            app.listCategories();
            app.DD_Category.Value = 'climate change';
            app.listIndicators();
            app.DD_Indicator.Value = 'GWP20';
            app.lciaIndex = 816;
            %profile viewer
            
            app.UIAxes.DataAspectRatio = [1 1 1];
            
            %app.BTN_LoadPushed();
        end

        % Menu selected function: mappingeditorMenu
        function mappingeditorMenuSelected(app, event)
            app.mappingApp = Mapping;
        end

        % Menu selected function: importMenu
        function importMenuSelected(app, event)
            
            exchangeStruct=xml2struct([app.options.exchangeFilePath 'exchange.xml']);
            app.I_asm = struct2I_Assembly(exchangeStruct);
            app.TV_Components.Children.delete;
            value = app.CB_ShowFeatures.Value;
            DisplayI_Assembly(app.TV_Components,app.I_asm,value);            
            
        end

        % Value changed function: CB_ShowFeatures
        function CB_ShowFeaturesValueChanged(app, event)
            
            app.model.root.displayAssemblyTree(app,app.CB_ShowFeatures.Value);
        end

        % Selection changed function: TV_Components
        function TV_ComponentsSelectionChanged(app, event)
            
            selectedNodes = app.TV_Components.SelectedNodes;
            app.activeElement = app.model.root.getElementById(selectedNodes.NodeData.id);
                        
            app.L_Selection.Text = app.activeElement.name;
            
            app.hideProcessEdits();
            
            app.BTN_Open.Text = "open stage";
            app.BTN_Close.Text = "close";
            app.BTN_Close.Enable = false;
            
            if isa(app.activeElement, "Component")
                app.mode = 'Component';
                app.classificationType = "none";
                app.activeElement.plot(app);
                app.setClassificationType([]);
                app.BTN_Loops.Enable = true;
                app.CB_IgnoreComponent.Value = app.activeElement.ignore;
                app.S_LifespanComponent.Value = app.activeElement.processParameter.lifespan.value;
                app.CB_ExchangableComponent.Value = app.activeElement.exchangable;
                switch app.activeElement.compatibilityStatus
                    case -1
                        app.L_Compatibility.Text = "not recycling compatible!";
                        app.L_Compatibility.FontColor = [244 136 136]/255;
                    case 0
                        app.L_Compatibility.Text = "possibly not recycling compatible!";
                        app.L_Compatibility.FontColor = [247 182 137]/255;
                    case 1
                        app.L_Compatibility.Text = "recycling compatible";
                        app.L_Compatibility.FontColor = [161 208 168]/255;
                end
            end
            if isa(app.activeElement, "Assembly")
                app.activeElement.plot(app);
                app.mode = "Assembly";
                app.P_Settings_Assembly.Title = "settings - " + app.activeElement.name;
                app.asmStageId = 0;
                app.BTN_Open.Text = "open stage impacts";
                app.BTN_Loops.Enable = false;
                app.S_LifespanAssembly.Value = app.activeElement.processParameter.lifespan.value;
            end
            if isprop(app.activeElement,'name')
                app.P_Settings_Component.Title = "settings - " + app.activeElement.name;
            else
                app.L_Navi.Text = '';
            end
            if isprop(app.activeElement,'material')
                app.DD_Material.Enable = true;
                app.DD_MaterialGroup.Enable = true;
                app.BTN_updateComponent.Enable = true;
            else
                app.DD_Material.Items = cell(0);
                app.DD_MaterialGroup.Items = cell(0);
                app.DD_Material.Enable = false;
                app.DD_MaterialGroup.Enable = false;
                app.BTN_updateComponent.Enable = false;
            end
            app.L_Mode.Text = lower(app.mode);
            app.displaySettings();
            app.BTN_Network.Enable = true;
        end

        % Button pushed function: BTN_Open
        function BTN_OpenButtonPushed(app, event)
            app.openElement();
        end

        % Callback function
        function CB_ShowImportedStructureValueChanged(app, event)
            value = app.CB_ShowImportedStructure.Value;
            if value                
                app.model.root = I_Assembly2Assembly(app,app.I_asm,true);
                %profile on
                h = waitbar(0.2,['classify assembly...']);
                app.model.root = app.model.root.classify(app);
                close(h);
                %profile viewer
                app.modelImpact = app.model.root.generateLCIA(app);
                app.model.root.displayAssemblyTree(app,app.CB_ShowFeatures.Value)                
                app.model.root.plot(app);
                app.activeElement = app.model.root;
            else
                app.CB_ShowFeaturesValueChanged();
                app.CB_ShowFeatures.Enable = true;
            end
        end

        % Menu selected function: loadMatrixMenu
        function loadMatrixMenuSelected(app, event)
            app.loadMatrix();
        end

        % Menu selected function: saveengineMenu
        function saveengineMenuSelected(app, event)
            app.processEngine.saveProcesses;
        end

        % Menu selected function: loadengineMenu
        function loadengineMenuSelected(app, event)
            app.processEngine = app.processEngine.loadProcesses;
        end

        % Menu selected function: exportappMenu
        function exportappMenuSelected(app, event)
            %lcm = app.model;
            %ref = app.referenceModel;
            %h = waitbar(0.2,['exporting model ...']);
            %save('debugLC','lcm','ref','-v7.3');
            %save('debugLC','lcm','-v7.3');
            %close(h);
            
            assignin("base",'app',app) % copies app object to workspace
            
        end

        % Callback function
        function BTN_AnalyseComponentButtonPushed(app, event)
            app.activeElement.stages(app.stageId).plot(app);
        end

        % Button pushed function: BTN_Exchange
        function BTN_ExchangeButtonPushed(app, event)
            app.processesApp = ProcessEditor(app,"exchange",@app.exchangeProcess);            
        end

        % Menu selected function: saveMenu
        function saveMenuSelected(app, event)
            saveProject(app);
        end

        % Menu selected function: openMenu
        function openMenuSelected(app, event)
            loadProject(app);            
        end

        % Button pushed function: BTN_Close
        function BTN_CloseButtonPushed(app, event)
            app.closeElement();
        end

        % Button pushed function: BTN_updateComponent
        function BTN_updateComponentButtonPushed(app, event)
            
            h = waitbar(0.2,['updating model ...']);
            
            materialGroup = app.DD_MaterialGroup.Value;
            material = app.DD_Material.Value;
            classification = app.DD_Classification.Value;
            ignore = app.CB_IgnoreComponent.Value;
            
            app.activeElement.ignore = ignore;
            
            if ~ignore
                
                app.TV_Components.SelectedNodes.Icon = "res\part.png";
                
                
                if app.S_LifespanComponent.Value ~= app.activeElement.processParameter.lifespan.value
                    app.activeElement.processParameter.lifespan.value = app.S_LifespanComponent.Value;
                end
                
                needsReassign = false;
                
                if  (material ~= app.activeElement.material)
                    needsReassign = true;
                end
                
                if ~strcmp(app.classificationType,"none")
                    if ~strcmp(classification,app.activeElement.classification.(app.classificationType))
                        app.activeElement.classification.(app.classificationType) = classification;
                        app.activeElement.generateStages(app);
                    end
                end
                if needsReassign
                    app.activeElement.materialGroup = materialGroup;
                    app.activeElement.material= material;
                    app.activeElement.assignComponentType(app);
                end
                
                app.activeElement.exchangable = app.CB_ExchangableComponent.Value;
                app.activeElement.quantity = app.S_quantity.Value;
                
            else
                app.TV_Components.SelectedNodes.Icon = "res\disenabled.png";
            end
            
            app.activeElement.parent.checkLifespans;
            analyseCompatibility(app,app.activeElement.parent);
            app.activeElement.plot(app);
            close(h)
            
            switch app.activeElement.compatibilityStatus
                case -1
                    app.L_Compatibility.Text = "not recycling compatible!";
                    app.L_Compatibility.FontColor = [244 136 136]/255;
                case 0
                    app.L_Compatibility.Text = "possibly not recycling compatible!";
                    app.L_Compatibility.FontColor = [247 182 137]/255;
                case 1
                    app.L_Compatibility.Text = "recycling compatible";
                    app.L_Compatibility.FontColor = [161 208 168]/255;
            end
            
        end

        % Value changed function: DD_Material
        function DD_MaterialValueChanged(app, event)
            value = app.DD_Material.Value;
            
        end

        % Button pushed function: BTN_updateProcess
        function BTN_updateProcessButtonPushed(app, event)
            process = app.activeElement.stages(app.stageId).operations(app.operationId).processes(app.processId);
            for a = 1:length(process.alternativeProcesses)
                if process.alternativeProcesses(a).name == app.DD_alternativeProcesses.Value
                    newProcess = process.alternativeProcesses(a);
                    break;
                end
            end
            if (app.L_Quantity.Text ~= "error")
                quantity = str2double(app.L_Quantity.Text);
                quantityExpression = app.E_Quantity.Value;
            end
            
            correction = app.E_Correction.Value;
            componentId = app.activeElement.id;
            app.model.root = app.model.root.updateProcess(app,process.id,newProcess,quantity,quantityExpression,correction);
            app.alternatives.standard = app.model.root;
            app.activeElement = app.model.root.getElementById(componentId);
%             if app.mode == "Operation"
%                 app.activeElement.stages(app.stageId).operations(app.operationId).displayOperation(app);
%             elseif app.mode == "EcoProcess" || app.mode == "HyProcess"
%                 app.activeElement.stages(app.stageId).operations(app.operationId).processes(app.processId).displayProcess(app,app.UIAxes);
%             end
            pro = app.activeElement.stages(app.stageId).operations(app.operationId).processes(app.processId);
            app.DD_availableLocations.Items = pro.locList;
            app.L_functionalunit.Text = string("functional unit [" + string(pro.unit) + "]");
            pro.plot(app,app.UIAxes)
            if app.options.normTime
                app.L_Navi.Text = app.activeElement.name + ' / ' + pro.parent.parent.name + ' / ' + pro.parent.name + ' / ' + pro.name + " (" + string(round(pro.generateLCIA(app),2)) +" "+ app.lciaUnit + " / yr)";
            else
                app.L_Navi.Text = app.activeElement.name + ' / ' + pro.parent.parent.name + ' / ' + pro.parent.name + ' / ' + pro.name + " (" + string(round(pro.generateLCIA(app),2)) +" "+ app.lciaUnit + ")";
            end
            
        end

        % Value changed function: DD_Indicator
        function DD_IndicatorValueChanged(app, event)
            app.updateLCIA();
        end

        % Value changed function: DD_Category
        function DD_CategoryValueChanged(app, event)
            app.listIndicators();
            %app.updateLCIA();
        end

        % Menu selected function: clearprocessengineMenu
        function clearprocessengineMenuSelected(app, event)
            app.processEngine.processes = EcoinventProcess.empty();
            app.processEngine.saveProcesses();
        end

        % Button pushed function: BTN_SensitivityAnalysis
        function BTN_SensitivityAnalysisButtonPushed(app, event)
            
            w = waitbar(0,['simulating parameter change...']);
            
            sensitivityApp(app);
            
            %             if isa(app.activeElement,'Component')
            %
            %                 referenceImpact = app.activeElement.generateLCIA(app);
            %                 [sensitivity, indiParameters]= analyseSensitivity(app,app.activeElement,app.activeElement);
            %
            %                 h = barh(app.UIAxes,sensitivity,0.5,'FaceColor','#FFAAAA','EdgeColor','none');
            %
            %                 text(app.UIAxes, 0 ,h.XData(end) + h.XData(1),'component impact change [%] on 10% parameter variation',"FontSize",18,"Color", [.3 .3 .35],'HorizontalAlignment', 'left','VerticalAlignment','top','FontWeight',"bold");
            %                 %text(app.UIAxes, h.YData, h.XData, indiParameters + "  " + string(sensitivity) + "%" ,'VerticalAlignment','middle', 'HorizontalAlignment', 'left', 'FontSize', 18, 'Color', [.3 .3 .35], 'FontWeight','normal','Interpreter','none');
            %                 text(app.UIAxes, zeros(length(h.YData),1)+.1, h.XData , indiParameters + "  " + string(sensitivity) + "%" ,'VerticalAlignment','middle', 'HorizontalAlignment', 'left', 'FontSize', 18, 'Color', [.3 .3 .35], 'FontWeight','normal','Interpreter','none');
            %
            %                 app.UIAxes.Interactions = [zoomInteraction, panInteraction];
            %                 enableDefaultInteractivity(app.UIAxes);
            %                 daspect(app.UIAxes,'auto')
            %
            %                 app.BTN_Open.Text = "open";
            %                 app.BTN_Open.Enable = false;
            %                 app.BTN_Close.Text = "close analysis";
            %                 app.BTN_Close.Enable = true;
            %                 app.mode = "Sensitivity";
            %             else
            %                 sensitivityApp(app);
            %             end
            
            close(w)
            
        end

        % Button pushed function: BTN_Loops
        function BTN_LoopsButtonPushed(app, event)
            loops(app);
        end

        % Button pushed function: BTN_Reference
        function BTN_ReferenceButtonPushed(app, event)
            %app.referenceModel = copy(app.model.root);
            
            [~,app.impactsReference] = app.model.root.generateLCIA(app);
            
            app.BTN_Compare.Enable = true;
            f = msgbox("reference set");
        end

        % Button pushed function: BTN_Compare
        function BTN_CompareButtonPushed(app, event)
            compareModels(app);
        end

        % Menu selected function: optionsMenu
        function optionsMenuSelected(app, event)
            optionsApp(app);
        end

        % Value changed function: E_Quantity
        function E_QuantityValueChanged(app, event)
            value = app.E_Quantity.Value;                      
            app.L_Quantity.Text = string(app.parse(value));
        end

        % Value changed function: Btn_LifeLink
        function Btn_LifeLinkValueChanged(app, event)
            value = app.Btn_LifeLink.Value;
            if value
                start(app.time);
                disp("starting");
            else
                stop(app.time);
                disp("stopping");
            end
        end

        % Button pushed function: BTN_Load
        function BTN_LoadPushed(app, event)
            
            tic
            
            exchangeStruct=xml2struct([app.options.exchangeFilePath 'exchange.xml']);
            app.I_asm = struct2I_Assembly(exchangeStruct);
            app.TV_Components.Children.delete;
            
            h = waitbar(0.2,['classify assembly...']);
            %app.model.root = I_Assembly2Assembly(app,app.I_asm,true);
            
            asm = I_Assembly2Assembly(app,app.I_asm,false);
            asm = mapjoints(asm,app.I_asm.joints);
            asm.classify(app);                  
            asm.setLifespans;            
            app.model.root = asm;
            
            app.alternatives.standard= app.model.root;
            
            close(h);
            
            app.modelImpact = app.model.root.generateLCIA(app);
                        
            app.model.root.displayAssemblyTree(app,app.CB_ShowFeatures.Value)
            expand(app.TV_Components,'all');
            
            analyseCompatibility(app,app.model.root);
            
            app.model.root.plot(app);
            app.activeElement = app.model.root;
            
            app.projectLoaded = true;
            app.L_Mode.Text = lower(app.mode);
            app.L_RefTime.Text = "reference time: " + app.options.referenceTime + " yr";
            app.setTVSelection();
            
            toc
            
        end

        % Button pushed function: BTN_Add_Eco
        function BTN_Add_EcoButtonPushed(app, event)
            app.processesApp = ProcessEditor(app,"add",@app.addProcess);
        end

        % Button pushed function: BTN_Remove
        function BTN_RemoveButtonPushed(app, event)
            app.activeElement.stages(app.stageId).operations(app.operationId).processes(app.processId) = [];
            %app.model = app.model.updateElement(app.activeElement);
            app.activeElement.stages(app.stageId).operations(app.operationId).plot(app);
            app.processId = 0;
            app.BTN_Remove.Enable = false;
            app.BTN_Exchange.Enable = false;
        end

        % Button pushed function: BTN_Network
        function BTN_NetworkButtonPushed(app, event)
            displayNetwork(app);
        end

        % Button pushed function: BTN_LCIA
        function BTN_LCIAButtonPushed(app, event)
            showLCIA(app);
        end

        % Button pushed function: BTN_EditParameter
        function BTN_EditParameterButtonPushed(app, event)
            ParameterApp(app);
        end

        % Button pushed function: BTN_Update
        function BTN_UpdateButtonPushed(app, event)
            tic
            updateModel(app);
            toc
        end

        % Button pushed function: BTN_updateAssembly
        function BTN_updateAssemblyButtonPushed(app, event)
            
        end

        % Button pushed function: BTN_Time
        function BTN_TimeButtonPushed(app, event)
            displayTime(app);
        end

        % Button pushed function: BTN_Compatibility
        function BTN_CompatibilityButtonPushed(app, event)
            CompatibilityApp(app,app.activeElement);
        end

        % Value changed function: DD_MaterialGroup
        function DD_MaterialGroupValueChanged(app, event)
            value = app.DD_MaterialGroup.Value;
            app.listMaterials(value);
            if ~strcmp(app.classificationType,"none")
                possibleClassificatons = app.classificationList.(app.classificationType){app.materialGroupList == value};
                app.DD_Classification.Items = possibleClassificatons;
            end
        end

        % Button pushed function: BTN_Explore
        function BTN_ExploreButtonPushed(app, event)
            app_exploreMaterials(app);
        end

        % Button pushed function: BTN_Add_Hybrid
        function BTN_Add_HybridButtonPushed(app, event)
            HybridProcessSelectionApp(app);
        end

        % Button pushed function: BTN_updateHybridProcess
        function BTN_updateHybridProcessButtonPushed(app, event)
            pro = app.activeElement.stages(app.stageId).operations(app.operationId).processes(app.processId);
            pro.quantityExpression = app.E_Hybrid_Quantity.Value;                    
            pro.plot(app,app.UIAxes)
            if app.options.normTime
                app.L_Navi.Text = app.activeElement.name + ' / ' + pro.parent.parent.name + ' / ' + pro.parent.name + ' / ' + pro.name + " (" + string(round(pro.generateLCIA(app),2)) +" "+ app.lciaUnit + " / yr)";
            else
                app.L_Navi.Text = app.activeElement.name + ' / ' + pro.parent.parent.name + ' / ' + pro.parent.name + ' / ' + pro.name + " (" + string(round(pro.generateLCIA(app),2)) +" "+ app.lciaUnit + ")";
            end
        end

        % Value changed function: E_Hybrid_Quantity
        function E_Hybrid_QuantityValueChanged(app, event)
            value = app.E_Hybrid_Quantity.Value;
            app.L_Hybrid_Quantity.Text = string(app.parse(value));
        end

        % Menu selected function: importfromxlsxMenu
        function importfromxlsxMenuSelected(app, event)
            xlsxImport(app)
        end

        % Menu selected function: createversionMenu
        function createversionMenuSelected(app, event)
            prompt = {'version name'};
            dlgtitle = 'version name';
            definput = {'newversion'};
            dims = [1 40];
            opts.Interpreter = 'tex';
            answer = inputdlg(prompt,dlgtitle,dims,definput,opts);            
            answer = string(matlab.lang.makeValidName(answer));            
            newAlternative = deepCopy(app,app.model.root);
            app.alternatives.(answer) = newAlternative;                                               
            menu = uimenu(app.selectversionMenu);
            menu.Text = answer;
            menu.MenuSelectedFcn = createCallbackFcn(app, @AlternativesSelection, true);                        
            menu = uimenu(app.removeversionMenu);
            menu.Text = answer;
            menu.MenuSelectedFcn = createCallbackFcn(app, @removeVersion, true);                        
        end

        % Menu selected function: standardMenu
        function AlternativesSelection(app, event)
            app.model.root = app.alternatives.(event.Source.Text);
            app.activeElement = app.model.root;        
            app.setTVSelection();
            app.activeElement.plot(app);
            app.L_Version.Text = "version: " + event.Source.Text;    
            app.activeVersion = event.Source.Text;
        end

        % Menu selected function: compareversionsMenu
        function compareversionsMenuSelected(app, event)
            compareVersions(app);
        end

        % Button pushed function: BTN_AnalyseAsm
        function BTN_AnalyseAsmButtonPushed(app, event)
            analyseAssembly(app);
        end

        % Menu selected function: saveplotaspdfMenu
        function saveplotaspdfMenuSelected(app, event)
            exportgraphics(app.UIAxes,'plot.pdf')
        end

        % Button pushed function: BTN_addOperation
        function BTN_addOperationButtonPushed(app, event)
            name = inputdlg('operation name','');
            op = Operation(name);
            app.activeElement.stages(app.stageId).addOperation(op);
            app.activeElement.stages(app.stageId).plot(app);
        end

        % Button pushed function: BTN_removeOperation
        function BTN_removeOperationButtonPushed(app, event)
            app.activeElement.stages(app.stageId).operations(app.operationId) = [];
            app.activeElement.stages(app.stageId).plot(app);
        end

        % Menu selected function: deleteMenu
        function deleteMenuSelected(app, event)
            if isa(app.activeElement, "Component")
               app.activeElement.parent.components(string({app.activeElement.parent.components.id}) == app.activeElement.id) = [];                
            else
               app.activeElement.parent.assemblies(string({app.activeElement.parent.assemblies.id}) == app.activeElement.id) = [];                
            end                      
            app.activeElement = app.model.root;
            app.model.root.displayAssemblyTree(app,app.CB_ShowFeatures.Value);
            app.model.root.plot(app);            
            
        end

        % Menu selected function: assemblyMenu
        function assemblyMenuSelected(app, event)
            name = inputdlg('assembly name','');
            asm = Assembly(name);
            if isa(app.activeElement, "Component")               
                app.activeElement.parent.addAssembly(asm);
            else
                app.activeElement.assemblies.addAssembly(asm);
            end
            app.model.root.displayAssemblyTree(app,app.CB_ShowFeatures.Value);
            app.model.root.plot(app);            
        end

        % Menu selected function: componentMenu
        function componentMenuSelected(app, event)
            if isa(app.activeElement, "Component")               
                NewComponentApp(app,app.activeElement.parent);
            else
                 NewComponentApp(app,app.activeElement);
            end
        end

        % Menu selected function: resulttableMenu
        function resulttableMenuSelected(app, event)
            exportResultTable(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LifeCycleDesignerUIFigure = uifigure('Visible', 'off');
            app.LifeCycleDesignerUIFigure.Name = 'Life Cycle Designer';
            app.fileMenu = uimenu(app.LifeCycleDesignerUIFigure);
            app.fileMenu.Text = 'file';
            app.newMenu = uimenu(app.fileMenu);
            app.newMenu.Text = 'new';
            app.importMenu = uimenu(app.fileMenu);
            app.importMenu.MenuSelectedFcn = createCallbackFcn(app, @importMenuSelected, true);
            app.importMenu.Text = 'import';
            app.importfromxlsxMenu = uimenu(app.fileMenu);
            app.importfromxlsxMenu.MenuSelectedFcn = createCallbackFcn(app, @importfromxlsxMenuSelected, true);
            app.importfromxlsxMenu.Text = 'import from xlsx';
            app.saveMenu = uimenu(app.fileMenu);
            app.saveMenu.MenuSelectedFcn = createCallbackFcn(app, @saveMenuSelected, true);
            app.saveMenu.Text = 'save';
            app.openMenu = uimenu(app.fileMenu);
            app.openMenu.MenuSelectedFcn = createCallbackFcn(app, @openMenuSelected, true);
            app.openMenu.Text = 'open';
            app.mappingMenu = uimenu(app.LifeCycleDesignerUIFigure);
            app.mappingMenu.Text = 'mapping';
            app.mappingeditorMenu = uimenu(app.mappingMenu);
            app.mappingeditorMenu.MenuSelectedFcn = createCallbackFcn(app, @mappingeditorMenuSelected, true);
            app.mappingeditorMenu.Text = 'mapping editor';
            app.settingsMenu = uimenu(app.LifeCycleDesignerUIFigure);
            app.settingsMenu.Text = 'settings';
            app.loadMatrixMenu = uimenu(app.settingsMenu);
            app.loadMatrixMenu.MenuSelectedFcn = createCallbackFcn(app, @loadMatrixMenuSelected, true);
            app.loadMatrixMenu.Text = 'load Matrix';
            app.saveengineMenu = uimenu(app.settingsMenu);
            app.saveengineMenu.MenuSelectedFcn = createCallbackFcn(app, @saveengineMenuSelected, true);
            app.saveengineMenu.Text = 'save engine';
            app.loadengineMenu = uimenu(app.settingsMenu);
            app.loadengineMenu.MenuSelectedFcn = createCallbackFcn(app, @loadengineMenuSelected, true);
            app.loadengineMenu.Text = 'load engine';
            app.optionsMenu = uimenu(app.settingsMenu);
            app.optionsMenu.MenuSelectedFcn = createCallbackFcn(app, @optionsMenuSelected, true);
            app.optionsMenu.Text = 'options';
            app.versionsMenu = uimenu(app.LifeCycleDesignerUIFigure);
            app.versionsMenu.Text = 'versions';
            app.createversionMenu = uimenu(app.versionsMenu);
            app.createversionMenu.MenuSelectedFcn = createCallbackFcn(app, @createversionMenuSelected, true);
            app.createversionMenu.Text = 'create version';
            app.selectversionMenu = uimenu(app.versionsMenu);
            app.selectversionMenu.Text = 'select version';
            app.standardMenu = uimenu(app.selectversionMenu);
            app.standardMenu.MenuSelectedFcn = createCallbackFcn(app, @AlternativesSelection, true);
            app.standardMenu.Text = 'standard';
            app.removeversionMenu = uimenu(app.versionsMenu);
            app.removeversionMenu.Text = 'remove version';
            app.compareversionsMenu = uimenu(app.versionsMenu);
            app.compareversionsMenu.MenuSelectedFcn = createCallbackFcn(app, @compareversionsMenuSelected, true);
            app.compareversionsMenu.Text = 'compare versions';
            app.debugMenu = uimenu(app.LifeCycleDesignerUIFigure);
            app.debugMenu.Text = 'debug';
            app.exportappMenu = uimenu(app.debugMenu);
            app.exportappMenu.MenuSelectedFcn = createCallbackFcn(app, @exportappMenuSelected, true);
            app.exportappMenu.Text = 'export app';
            app.clearprocessengineMenu = uimenu(app.debugMenu);
            app.clearprocessengineMenu.MenuSelectedFcn = createCallbackFcn(app, @clearprocessengineMenuSelected, true);
            app.clearprocessengineMenu.Text = 'clear process engine';
            app.saveplotaspdfMenu = uimenu(app.debugMenu);
            app.saveplotaspdfMenu.MenuSelectedFcn = createCallbackFcn(app, @saveplotaspdfMenuSelected, true);
            app.saveplotaspdfMenu.Text = 'save plot as pdf';
            app.exportMenu = uimenu(app.LifeCycleDesignerUIFigure);
            app.exportMenu.Text = 'export';
            app.resulttableMenu = uimenu(app.exportMenu);
            app.resulttableMenu.MenuSelectedFcn = createCallbackFcn(app, @resulttableMenuSelected, true);
            app.resulttableMenu.Text = 'result table';
            app.GridLayout = uigridlayout(app.LifeCycleDesignerUIFigure);
            app.Btn_LifeLink = uibutton(app.GridLayout, 'state');
            app.Btn_LifeLink.ValueChangedFcn = createCallbackFcn(app, @Btn_LifeLinkValueChanged, true);
            app.Btn_LifeLink.Text = 'link';
            app.CB_ShowFeatures = uicheckbox(app.GridLayout);
            app.CB_ShowFeatures.ValueChangedFcn = createCallbackFcn(app, @CB_ShowFeaturesValueChanged, true);
            app.CB_ShowFeatures.Text = 'show features';
            app.TV_Components = uitree(app.GridLayout);
            app.TV_Components.SelectionChangedFcn = createCallbackFcn(app, @TV_ComponentsSelectionChanged, true);
            app.analysisPanel = uipanel(app.GridLayout);
            app.analysisPanel.Title = ' analysis';
            app.L_Indicator = uilabel(app.analysisPanel);
            app.L_Indicator.Text = 'product system';
            app.indicatorDropDownLabel = uilabel(app.analysisPanel);
            app.indicatorDropDownLabel.Text = 'indicator';
            app.DD_Indicator = uidropdown(app.analysisPanel);
            app.DD_Indicator.Items = {};
            app.DD_Indicator.ValueChangedFcn = createCallbackFcn(app, @DD_IndicatorValueChanged, true);
            app.DD_Indicator.Value = {};
            app.categoryLabel = uilabel(app.analysisPanel);
            app.categoryLabel.Text = 'category';
            app.DD_Category = uidropdown(app.analysisPanel);
            app.DD_Category.Items = {};
            app.DD_Category.ValueChangedFcn = createCallbackFcn(app, @DD_CategoryValueChanged, true);
            app.DD_Category.Value = {};
            app.L_LciaMethod = uilabel(app.analysisPanel);
            app.L_LciaMethod.Text = 'LciaMethod';
            app.BTN_Network = uibutton(app.analysisPanel, 'push');
            app.BTN_Network.ButtonPushedFcn = createCallbackFcn(app, @BTN_NetworkButtonPushed, true);
            app.BTN_Network.Text = 'network';
            app.BTN_Reference = uibutton(app.analysisPanel, 'push');
            app.BTN_Reference.ButtonPushedFcn = createCallbackFcn(app, @BTN_ReferenceButtonPushed, true);
            app.BTN_Reference.Text = 'set reference';
            app.BTN_Compare = uibutton(app.analysisPanel, 'push');
            app.BTN_Compare.ButtonPushedFcn = createCallbackFcn(app, @BTN_CompareButtonPushed, true);
            app.BTN_Compare.Enable = 'off';
            app.BTN_Compare.Text = 'compare to ref';
            app.L_Selection = uilabel(app.analysisPanel);
            app.L_Selection.Text = '';
            app.BTN_SensitivityAnalysis = uibutton(app.analysisPanel, 'push');
            app.BTN_SensitivityAnalysis.ButtonPushedFcn = createCallbackFcn(app, @BTN_SensitivityAnalysisButtonPushed, true);
            app.BTN_SensitivityAnalysis.Text = 'analysis & advice';
            app.BTN_LCIA = uibutton(app.analysisPanel, 'push');
            app.BTN_LCIA.ButtonPushedFcn = createCallbackFcn(app, @BTN_LCIAButtonPushed, true);
            app.BTN_LCIA.Text = 'all categories';
            app.BTN_Time = uibutton(app.analysisPanel, 'push');
            app.BTN_Time.ButtonPushedFcn = createCallbackFcn(app, @BTN_TimeButtonPushed, true);
            app.BTN_Time.Text = 'course of time';
            app.BTN_AnalyseAsm = uibutton(app.analysisPanel, 'push');
            app.BTN_AnalyseAsm.ButtonPushedFcn = createCallbackFcn(app, @BTN_AnalyseAsmButtonPushed, true);
            app.BTN_AnalyseAsm.Text = 'components';
            app.P_Settings_Process = uipanel(app.GridLayout);
            app.P_Settings_Process.Title = ' settings';
            app.L_functionalunit = uilabel(app.P_Settings_Process);
            app.L_functionalunit.Text = 'functional unit';
            app.L_availableProcesses = uilabel(app.P_Settings_Process);
            app.L_availableProcesses.Text = 'alternative processes';
            app.DD_alternativeProcesses = uidropdown(app.P_Settings_Process);
            app.DD_alternativeProcesses.Items = {};
            app.DD_alternativeProcesses.Value = {};
            app.BTN_updateProcess = uibutton(app.P_Settings_Process, 'push');
            app.BTN_updateProcess.ButtonPushedFcn = createCallbackFcn(app, @BTN_updateProcessButtonPushed, true);
            app.BTN_updateProcess.Text = 'update Process';
            app.E_Correction = uieditfield(app.P_Settings_Process, 'numeric');
            app.L_Correction = uilabel(app.P_Settings_Process);
            app.L_Correction.Text = 'correction factor';
            app.L_availableLocations = uilabel(app.P_Settings_Process);
            app.L_availableLocations.Text = 'alternative locations';
            app.DD_availableLocations = uidropdown(app.P_Settings_Process);
            app.DD_availableLocations.Items = {};
            app.DD_availableLocations.Value = {};
            app.L_Quantity = uilabel(app.P_Settings_Process);
            app.L_Quantity.Text = '';
            app.E_Quantity = uieditfield(app.P_Settings_Process, 'text');
            app.E_Quantity.ValueChangedFcn = createCallbackFcn(app, @E_QuantityValueChanged, true);
            app.E_Quantity.Tooltip = {'available Parameter:'; '"mass"'; '"volume"'; '"density"'; '"boundingBoxX"'; '"boundingBoxY"'; '"boundingBoxZ"'; '"surface"'; '"paintMass"'; '"screwMass"'; '"weldLength"'; '"adhesiveMass"'; '"massBy100km"'; '"massRemovedMilling"'; '"massRemovedTurning"'; '"massRecycling"'; '"massDisposal"'; '"massRefurbishment"'; '"massRemanufacturing"'; '"massMaintenance"'};
            app.L_Navi = uilabel(app.GridLayout);
            app.L_Navi.Text = '';
            app.BTN_Load = uibutton(app.GridLayout, 'push');
            app.BTN_Load.ButtonPushedFcn = createCallbackFcn(app, @BTN_LoadPushed, true);
            app.BTN_Load.Text = 'load';
            app.L_Mode = uilabel(app.GridLayout);
            app.L_Mode.Text = '';
            app.BTN_Open = uibutton(app.GridLayout, 'push');
            app.BTN_Open.ButtonPushedFcn = createCallbackFcn(app, @BTN_OpenButtonPushed, true);
            app.BTN_Open.Enable = 'off';
            app.BTN_Open.Text = 'open stage';
            app.BTN_Close = uibutton(app.GridLayout, 'push');
            app.BTN_Close.ButtonPushedFcn = createCallbackFcn(app, @BTN_CloseButtonPushed, true);
            app.BTN_Close.Enable = 'off';
            app.BTN_Close.Text = 'close';
            app.L_RefTime = uilabel(app.GridLayout);
            app.L_RefTime.Text = '';
            app.BTN_Update = uibutton(app.GridLayout, 'push');
            app.BTN_Update.ButtonPushedFcn = createCallbackFcn(app, @BTN_UpdateButtonPushed, true);
            app.BTN_Update.Text = 'update';
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = 0;
            app.UIAxes.YTickLabel = '';
            app.L_Version = uilabel(app.GridLayout);
            app.L_Version.Text = 'version: standard';
            app.P_Settings_Stage = uipanel(app.LifeCycleDesignerUIFigure);
            app.P_Settings_Stage.Title = 'stage';
            app.BTN_addOperation = uibutton(app.P_Settings_Stage, 'push');
            app.BTN_addOperation.ButtonPushedFcn = createCallbackFcn(app, @BTN_addOperationButtonPushed, true);
            app.BTN_addOperation.Text = 'add Operation';
            app.BTN_removeOperation = uibutton(app.P_Settings_Stage, 'push');
            app.BTN_removeOperation.ButtonPushedFcn = createCallbackFcn(app, @BTN_removeOperationButtonPushed, true);
            app.BTN_removeOperation.Text = 'remove Operation';
            app.P_Settings_Operation = uipanel(app.LifeCycleDesignerUIFigure);
            app.P_Settings_Operation.Title = 'operation';
            app.BTN_Exchange = uibutton(app.P_Settings_Operation, 'push');
            app.BTN_Exchange.ButtonPushedFcn = createCallbackFcn(app, @BTN_ExchangeButtonPushed, true);
            app.BTN_Exchange.Text = 'exchange process';
            app.BTN_Add_Eco = uibutton(app.P_Settings_Operation, 'push');
            app.BTN_Add_Eco.ButtonPushedFcn = createCallbackFcn(app, @BTN_Add_EcoButtonPushed, true);
            app.BTN_Add_Eco.Text = 'add ecoinvent process';
            app.BTN_Remove = uibutton(app.P_Settings_Operation, 'push');
            app.BTN_Remove.ButtonPushedFcn = createCallbackFcn(app, @BTN_RemoveButtonPushed, true);
            app.BTN_Remove.Text = 'remove process';
            app.BTN_Add_Hybrid = uibutton(app.P_Settings_Operation, 'push');
            app.BTN_Add_Hybrid.ButtonPushedFcn = createCallbackFcn(app, @BTN_Add_HybridButtonPushed, true);
            app.BTN_Add_Hybrid.Text = 'add hybrid process';
            app.P_Settings_Assembly = uipanel(app.LifeCycleDesignerUIFigure);
            app.P_Settings_Assembly.Title = 'assembly';
            app.CB_IgnoreAssembly = uicheckbox(app.P_Settings_Assembly);
            app.CB_IgnoreAssembly.Text = 'ignore assembly';
            app.BTN_LoopsAssembly = uibutton(app.P_Settings_Assembly, 'push');
            app.BTN_LoopsAssembly.Enable = 'off';
            app.BTN_LoopsAssembly.Text = 'adjust loop rates';
            app.BTN_updateAssembly = uibutton(app.P_Settings_Assembly, 'push');
            app.BTN_updateAssembly.ButtonPushedFcn = createCallbackFcn(app, @BTN_updateAssemblyButtonPushed, true);
            app.BTN_updateAssembly.Text = 'update assembly';
            app.lifespanyrLabel_2 = uilabel(app.P_Settings_Assembly);
            app.lifespanyrLabel_2.Enable = 'off';
            app.lifespanyrLabel_2.Text = 'lifespan [yr]';
            app.S_LifespanAssembly = uispinner(app.P_Settings_Assembly);
            app.S_LifespanAssembly.Limits = [0 Inf];
            app.S_LifespanAssembly.Enable = 'off';
            app.S_LifespanAssembly.Value = 10;
            app.ExchangableAssembly = uicheckbox(app.P_Settings_Assembly);
            app.ExchangableAssembly.Text = 'exchangable during use';
            app.P_Settings_Component = uipanel(app.LifeCycleDesignerUIFigure);
            app.P_Settings_Component.Title = 'component';
            app.materialDropDownLabel = uilabel(app.P_Settings_Component);
            app.materialDropDownLabel.Text = 'material';
            app.DD_Material = uidropdown(app.P_Settings_Component);
            app.DD_Material.Items = {};
            app.DD_Material.ValueChangedFcn = createCallbackFcn(app, @DD_MaterialValueChanged, true);
            app.DD_Material.Value = {};
            app.classificationLabel = uilabel(app.P_Settings_Component);
            app.classificationLabel.Text = 'classification';
            app.DD_Classification = uidropdown(app.P_Settings_Component);
            app.DD_Classification.Items = {};
            app.DD_Classification.Value = {};
            app.masskgEditFieldLabel = uilabel(app.P_Settings_Component);
            app.masskgEditFieldLabel.Enable = 'off';
            app.masskgEditFieldLabel.Text = 'mass [kg]';
            app.E_Mass = uieditfield(app.P_Settings_Component, 'numeric');
            app.E_Mass.Enable = 'off';
            app.volumemLabel = uilabel(app.P_Settings_Component);
            app.volumemLabel.Enable = 'off';
            app.volumemLabel.Text = 'volume [m³]';
            app.E_Volume = uieditfield(app.P_Settings_Component, 'numeric');
            app.E_Volume.Enable = 'off';
            app.BTN_Loops = uibutton(app.P_Settings_Component, 'push');
            app.BTN_Loops.ButtonPushedFcn = createCallbackFcn(app, @BTN_LoopsButtonPushed, true);
            app.BTN_Loops.Text = 'loops';
            app.BTN_updateComponent = uibutton(app.P_Settings_Component, 'push');
            app.BTN_updateComponent.ButtonPushedFcn = createCallbackFcn(app, @BTN_updateComponentButtonPushed, true);
            app.BTN_updateComponent.Text = 'update component';
            app.CB_IgnoreComponent = uicheckbox(app.P_Settings_Component);
            app.CB_IgnoreComponent.Text = 'ignore component';
            app.lifespanyrLabel = uilabel(app.P_Settings_Component);
            app.lifespanyrLabel.Text = 'lifespan [yr]';
            app.S_LifespanComponent = uispinner(app.P_Settings_Component);
            app.S_LifespanComponent.Limits = [0 Inf];
            app.S_LifespanComponent.ValueDisplayFormat = '%.0f';
            app.S_LifespanComponent.Value = 10;
            app.BTN_EditParameter = uibutton(app.P_Settings_Component, 'push');
            app.BTN_EditParameter.ButtonPushedFcn = createCallbackFcn(app, @BTN_EditParameterButtonPushed, true);
            app.BTN_EditParameter.Text = 'parameters';
            app.CB_ExchangableComponent = uicheckbox(app.P_Settings_Component);
            app.CB_ExchangableComponent.Text = 'exchangable during use';
            app.BTN_Compatibility = uibutton(app.P_Settings_Component, 'push');
            app.BTN_Compatibility.ButtonPushedFcn = createCallbackFcn(app, @BTN_CompatibilityButtonPushed, true);
            app.BTN_Compatibility.Text = 'compatibility';
            app.groupLabel = uilabel(app.P_Settings_Component);
            app.groupLabel.Text = 'group';
            app.DD_MaterialGroup = uidropdown(app.P_Settings_Component);
            app.DD_MaterialGroup.Items = {};
            app.DD_MaterialGroup.ValueChangedFcn = createCallbackFcn(app, @DD_MaterialGroupValueChanged, true);
            app.DD_MaterialGroup.Value = {};
            app.BTN_Explore = uibutton(app.P_Settings_Component, 'push');
            app.BTN_Explore.ButtonPushedFcn = createCallbackFcn(app, @BTN_ExploreButtonPushed, true);
            app.BTN_Explore.Text = 'explore';
            app.L_Compatibility = uilabel(app.P_Settings_Component);
            app.L_Compatibility.Text = '';
            app.quantityLabel = uilabel(app.P_Settings_Component);
            app.quantityLabel.Text = 'quantity';
            app.S_quantity = uispinner(app.P_Settings_Component);
            app.S_quantity.Limits = [1 Inf];
            app.S_quantity.Value = 1;
            app.P_Settings_HyProcess = uipanel(app.LifeCycleDesignerUIFigure);
            app.P_Settings_HyProcess.Title = 'hybrid process';
            app.L_Hybrid_Functionalunit = uilabel(app.P_Settings_HyProcess);
            app.L_Hybrid_Functionalunit.Text = 'functional unit';
            app.E_Hybrid_Quantity = uieditfield(app.P_Settings_HyProcess, 'text');
            app.E_Hybrid_Quantity.ValueChangedFcn = createCallbackFcn(app, @E_Hybrid_QuantityValueChanged, true);
            app.E_Hybrid_Quantity.Tooltip = {'available Parameter:'; '"mass"'; '"volume"'; '"density"'; '"boundingBoxX"'; '"boundingBoxY"'; '"boundingBoxZ"'; '"surface"'; '"paintMass"'; '"screwMass"'; '"weldLength"'; '"adhesiveMass"'; '"massBy100km"'; '"massRemovedMilling"'; '"massRemovedTurning"'; '"massRecycling"'; '"massDisposal"'; '"massRefurbishment"'; '"massRemanufacturing"'; '"massMaintenance"'};
            app.BTN_updateHybridProcess = uibutton(app.P_Settings_HyProcess, 'push');
            app.BTN_updateHybridProcess.ButtonPushedFcn = createCallbackFcn(app, @BTN_updateHybridProcessButtonPushed, true);
            app.BTN_updateHybridProcess.Text = 'update Process';
            app.L_Hybrid_Quantity = uilabel(app.P_Settings_HyProcess);
            app.L_Hybrid_Quantity.Text = '';
            app.CM_tree = uicontextmenu(app.LifeCycleDesignerUIFigure);
            app.deleteMenu = uimenu(app.CM_tree);
            app.deleteMenu.MenuSelectedFcn = createCallbackFcn(app, @deleteMenuSelected, true);
            app.deleteMenu.Text = 'delete';
            app.addMenu = uimenu(app.CM_tree);
            app.addMenu.Text = 'add';
            app.assemblyMenu = uimenu(app.addMenu);
            app.assemblyMenu.MenuSelectedFcn = createCallbackFcn(app, @assemblyMenuSelected, true);
            app.assemblyMenu.Text = 'assembly';
            app.componentMenu = uimenu(app.addMenu);
            app.componentMenu.MenuSelectedFcn = createCallbackFcn(app, @componentMenuSelected, true);
            app.componentMenu.Text = 'component';
            % Show the figure after all components are created
            app.LifeCycleDesignerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LCD

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LifeCycleDesignerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LifeCycleDesignerUIFigure)
        end
    end
end