classdef NewComponentApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        newComponentUIFigure        matlab.ui.Figure
        materialgroupDropDownLabel  matlab.ui.control.Label
        DD_materialGroup            matlab.ui.control.DropDown
        materialDropDownLabel       matlab.ui.control.Label
        DD_material                 matlab.ui.control.DropDown
        nameEditFieldLabel          matlab.ui.control.Label
        E_name                      matlab.ui.control.EditField
        massEditFieldLabel          matlab.ui.control.Label
        E_mass                      matlab.ui.control.NumericEditField
        CB_exchangable              matlab.ui.control.CheckBox
        kgLabel                     matlab.ui.control.Label
        surfaceEditFieldLabel       matlab.ui.control.Label
        E_surface                   matlab.ui.control.NumericEditField
        mmLabel                     matlab.ui.control.Label
        mmLabel_2                   matlab.ui.control.Label
        mmLabel_3                   matlab.ui.control.Label
        xEditFieldLabel             matlab.ui.control.Label
        E_BBX                       matlab.ui.control.NumericEditField
        yEditFieldLabel             matlab.ui.control.Label
        E_BBY                       matlab.ui.control.NumericEditField
        zEditFieldLabel             matlab.ui.control.Label
        E_BBZ                       matlab.ui.control.NumericEditField
        boundingboxLabel            matlab.ui.control.Label
        mmLabel_4                   matlab.ui.control.Label
        applyButton                 matlab.ui.control.Button
        cancelButton                matlab.ui.control.Button
    end

    
    properties (Access = private)
        mainApp % Description
        processParameter
        parentObj
    end
    
    methods (Access = private)
        
        function listMaterials(app,materialGroup)
            MP = app.mainApp.materialProperties;
            app.DD_material.Items = cellstr(MP.Material(MP.Group == string(materialGroup)));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, parent)
            app.parentObj = parent;
            app.mainApp = mainApp;
            app.DD_materialGroup.Items = mainApp.materialGroupList;
            app.listMaterials(app.DD_materialGroup.Value);
            
        end

        % Value changed function: E_mass
        function E_massValueChanged(app, event)
            value = app.E_mass.Value;
            app.processParameter.mass.value = value;
            app.processParameter.density.value = app.mainApp.materialProperties.Density(string(app.mainApp.materialProperties.Material) == app.DD_material.Value);
            app.processParameter.volume.value = app.processParameter.mass.value / app.processParameter.density.value;
            
            %assumption: part is is sphere shaped -> V = 4/3*pi*r^3
            r = (3*app.processParameter.volume.value/(4*pi))^(1/3);
            bx = 2*r;
            by = 2*r;
            bz = 2*r;
            app.processParameter.boundingBoxX.value = bx;
            app.processParameter.boundingBoxY.value = by;
            app.processParameter.boundingBoxZ.value = bz;
            app.E_BBX.Value = bx * 1000;
            app.E_BBY.Value = by * 1000;
            app.E_BBZ.Value = bz * 1000;
            
            app.processParameter.surface.value = 4*pi*r^2;
            app.E_surface.Value = app.processParameter.surface.value * (1000)^3;
            app.processParameter.paintMass.value = app.processParameter.surface.value / 16.5 * 1.1;
            
            
        end

        % Button pushed function: cancelButton
        function cancelButtonPushed(app, event)
            delete(app);
        end

        % Value changed function: E_BBX
        function E_BBXValueChanged(app, event)
            app.processParameter.boundingBoxX.value = app.E_BBX.Value;
        end

        % Value changed function: E_BBY
        function E_BBYValueChanged(app, event)
            value = app.E_BBY.Value;
            app.processParameter.boundingBoxY.value = app.E_BBY.Value;
        end

        % Value changed function: E_BBZ
        function E_BBZValueChanged(app, event)
            app.processParameter.boundingBoxZ.value = app.E_BBZ.Value;
        end

        % Value changed function: E_surface
        function E_surfaceValueChanged(app, event)
            app.processParameter.surface.value = app.E_surface.Value / (1000)^3;
            app.processParameter.paintMass.value = app.processParameter.surface.value / 16.5 * 1.1;
        end

        % Button pushed function: applyButton
        function applyButtonPushed(app, event)
            name = app.E_name.Value;
            material = string(app.DD_material.Value);            
            com = Component(app.mainApp,name,material,app.processParameter);
            app.parentObj.addComponent(com,app.mainApp,true);
            app.mainApp.activeElement = com;
            app.mainApp.model.root.displayAssemblyTree(app.mainApp,app.mainApp.CB_ShowFeatures.Value);
            com.exchangable = app.CB_exchangable.Value;
            com.plot(app.mainApp);
            delete(app);
        end

        % Value changed function: DD_materialGroup
        function DD_materialGroupValueChanged(app, event)
            value = app.DD_materialGroup.Value;
            app.listMaterials(value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.newComponentUIFigure = uifigure('Visible', 'off');
            app.newComponentUIFigure.Name = 'new Component';
            app.materialgroupDropDownLabel = uilabel(app.newComponentUIFigure);
            app.materialgroupDropDownLabel.Text = 'material group';
            app.DD_materialGroup = uidropdown(app.newComponentUIFigure);
            app.DD_materialGroup.ValueChangedFcn = createCallbackFcn(app, @DD_materialGroupValueChanged, true);
            app.materialDropDownLabel = uilabel(app.newComponentUIFigure);
            app.materialDropDownLabel.Text = 'material';
            app.DD_material = uidropdown(app.newComponentUIFigure);
            app.nameEditFieldLabel = uilabel(app.newComponentUIFigure);
            app.nameEditFieldLabel.Text = 'name';
            app.E_name = uieditfield(app.newComponentUIFigure, 'text');
            app.massEditFieldLabel = uilabel(app.newComponentUIFigure);
            app.massEditFieldLabel.Text = 'mass';
            app.E_mass = uieditfield(app.newComponentUIFigure, 'numeric');
            app.E_mass.ValueChangedFcn = createCallbackFcn(app, @E_massValueChanged, true);
            app.CB_exchangable = uicheckbox(app.newComponentUIFigure);
            app.CB_exchangable.Text = 'exchangable';
            app.kgLabel = uilabel(app.newComponentUIFigure);
            app.kgLabel.Text = 'kg';
            app.surfaceEditFieldLabel = uilabel(app.newComponentUIFigure);
            app.surfaceEditFieldLabel.Text = 'surface';
            app.E_surface = uieditfield(app.newComponentUIFigure, 'numeric');
            app.E_surface.ValueChangedFcn = createCallbackFcn(app, @E_surfaceValueChanged, true);
            app.mmLabel = uilabel(app.newComponentUIFigure);
            app.mmLabel.Text = 'mm';
            app.mmLabel_2 = uilabel(app.newComponentUIFigure);
            app.mmLabel_2.Text = 'mm';
            app.mmLabel_3 = uilabel(app.newComponentUIFigure);
            app.mmLabel_3.Text = 'mm';
            app.xEditFieldLabel = uilabel(app.newComponentUIFigure);
            app.xEditFieldLabel.Text = 'x';
            app.E_BBX = uieditfield(app.newComponentUIFigure, 'numeric');
            app.E_BBX.ValueChangedFcn = createCallbackFcn(app, @E_BBXValueChanged, true);
            app.yEditFieldLabel = uilabel(app.newComponentUIFigure);
            app.yEditFieldLabel.Text = 'y';
            app.E_BBY = uieditfield(app.newComponentUIFigure, 'numeric');
            app.E_BBY.ValueChangedFcn = createCallbackFcn(app, @E_BBYValueChanged, true);
            app.zEditFieldLabel = uilabel(app.newComponentUIFigure);
            app.zEditFieldLabel.Text = 'z';
            app.E_BBZ = uieditfield(app.newComponentUIFigure, 'numeric');
            app.E_BBZ.ValueChangedFcn = createCallbackFcn(app, @E_BBZValueChanged, true);
            app.boundingboxLabel = uilabel(app.newComponentUIFigure);
            app.boundingboxLabel.Text = 'bounding box';
            app.mmLabel_4 = uilabel(app.newComponentUIFigure);
            app.mmLabel_4.Text = 'mm³';
            app.applyButton = uibutton(app.newComponentUIFigure, 'push');
            app.applyButton.ButtonPushedFcn = createCallbackFcn(app, @applyButtonPushed, true);
            app.applyButton.Text = 'apply';
            app.cancelButton = uibutton(app.newComponentUIFigure, 'push');
            app.cancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelButtonPushed, true);
            app.cancelButton.Text = 'cancel';
            % Show the figure after all components are created
            app.newComponentUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NewComponentApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.newComponentUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.newComponentUIFigure)
        end
    end
end