classdef ParameterApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ParameterEditorUIFigure  matlab.ui.Figure
        T_Auto                   matlab.ui.control.Table
        BTN_cancel               matlab.ui.control.Button
        BTN_apply                matlab.ui.control.Button
        T_Custom                 matlab.ui.control.Table
        autoparametersLabel      matlab.ui.control.Label
        customparametersLabel    matlab.ui.control.Label
        BTN_delete               matlab.ui.control.Button
        BTN_new                  matlab.ui.control.Button
    end

    
    properties (Access = private)
        mainApp % Description
        selectionCustom
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.mainApp = mainApp;            
            
            param = mainApp.activeElement.processParameter;            
            parameter = fieldnames(param);
            value = cell(length(parameter),1);
            dependant = cell(length(parameter),1);            
            for i = 1:length(parameter)
                value(i) = {string(param.(string(parameter(i))).value)};
                dependant(i) = {string(param.(string(parameter(i))).dependant)};
            end            
            tab = table(parameter,value,dependant);            
            app.T_Auto.Data = tab;
            
            param = mainApp.activeElement.customParameter; 
            if ~isempty(param)
                parameter = fieldnames(param);
                value = cell(length(parameter),1);            
                for i = 1:length(parameter)
                    value(i) = {string(param.(string(parameter(i))).value)};                
                end            
                tab = table(parameter,value);            
                app.T_Custom.Data = tab;
            end
        end

        % Button pushed function: BTN_cancel
        function BTN_cancelButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: BTN_apply
        function BTN_applyButtonPushed(app, event)
            
            % apply process parameter
            param = app.mainApp.activeElement.processParameter;
            names = fieldnames(param);                                                                    
            for i = 1:length(names)
                param.(string(names(i))).value = str2double(app.T_Auto.Data.value{i});                                
            end                                    
            app.mainApp.activeElement.processParameter = param;
            
            %apply custom parameter
            cparam = [];
            if ~isempty(app.T_Custom.Data)
                for i = 1:height(app.T_Custom.Data)
                    cparam.(string(app.T_Custom.Data.parameter(i))).value = str2double(app.T_Custom.Data.value{i});
                    cparam.(string(app.T_Custom.Data.parameter(i))).dependant = true;
                end
            end
            app.mainApp.activeElement.customParameter = cparam;            
            app.mainApp.activeElement.displayComponent(app.mainApp);
            
            delete(app);
        end

        % Button pushed function: BTN_new
        function BTN_newButtonPushed(app, event)
          
            parameter = "newParameter";
            value = "1";
            new = table(parameter,value);
            app.T_Custom.Data = [app.T_Custom.Data ; new];
        end

        % Button pushed function: BTN_delete
        function BTN_deleteButtonPushed(app, event)
            app.T_Custom.Data(app.selectionCustom(1),:) = [];
        end

        % Cell selection callback: T_Custom
        function T_CustomCellSelection(app, event)
            app.selectionCustom = event.Indices;
            app.BTN_delete.Enable = true;
            disp("TRIGGERED!");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ParameterEditorUIFigure = uifigure('Visible', 'off');
            app.ParameterEditorUIFigure.Name = 'Parameter Editor';
            app.T_Auto = uitable(app.ParameterEditorUIFigure);
            app.T_Auto.ColumnName = {'parameter'; 'value'; 'dependant'};
            app.T_Auto.RowName = {};
            app.T_Auto.ColumnEditable = [false true false];
            app.BTN_cancel = uibutton(app.ParameterEditorUIFigure, 'push');
            app.BTN_cancel.ButtonPushedFcn = createCallbackFcn(app, @BTN_cancelButtonPushed, true);
            app.BTN_cancel.Text = 'cancel';
            app.BTN_apply = uibutton(app.ParameterEditorUIFigure, 'push');
            app.BTN_apply.ButtonPushedFcn = createCallbackFcn(app, @BTN_applyButtonPushed, true);
            app.BTN_apply.Text = 'apply';
            app.T_Custom = uitable(app.ParameterEditorUIFigure);
            app.T_Custom.ColumnName = {'parameter'; 'value'};
            app.T_Custom.RowName = {};
            app.T_Custom.ColumnEditable = true;
            app.T_Custom.CellSelectionCallback = createCallbackFcn(app, @T_CustomCellSelection, true);
            app.autoparametersLabel = uilabel(app.ParameterEditorUIFigure);
            app.autoparametersLabel.Text = 'auto parameters';
            app.customparametersLabel = uilabel(app.ParameterEditorUIFigure);
            app.customparametersLabel.Text = 'custom parameters';
            app.BTN_delete = uibutton(app.ParameterEditorUIFigure, 'push');
            app.BTN_delete.ButtonPushedFcn = createCallbackFcn(app, @BTN_deleteButtonPushed, true);
            app.BTN_delete.Enable = 'off';
            app.BTN_delete.Text = 'delete';
            app.BTN_new = uibutton(app.ParameterEditorUIFigure, 'push');
            app.BTN_new.ButtonPushedFcn = createCallbackFcn(app, @BTN_newButtonPushed, true);
            app.BTN_new.Text = 'new';
            % Show the figure after all components are created
            app.ParameterEditorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ParameterApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ParameterEditorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ParameterEditorUIFigure)
        end
    end
end