classdef app_exploreMaterials < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        explorematerialsUIFigure  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        UITable                   matlab.ui.control.Table
        BTN_search                matlab.ui.control.Button
        CB_Density                matlab.ui.control.CheckBox
        SP_Density_From           matlab.ui.control.Spinner
        SP_Density_To             matlab.ui.control.Spinner
        toLabel                   matlab.ui.control.Label
        CB_Yield                  matlab.ui.control.CheckBox
        SP_Yield_From             matlab.ui.control.Spinner
        SP_Yield_To               matlab.ui.control.Spinner
        toLabel_2                 matlab.ui.control.Label
        CB_Tensile                matlab.ui.control.CheckBox
        SP_Tensile_From           matlab.ui.control.Spinner
        SP_Tensile_To             matlab.ui.control.Spinner
        toLabel_3                 matlab.ui.control.Label
        CB_Young                  matlab.ui.control.CheckBox
        SP_Young_From             matlab.ui.control.Spinner
        SP_Young_To               matlab.ui.control.Spinner
        toLabel_4                 matlab.ui.control.Label
        CB_Poisson                matlab.ui.control.CheckBox
        SP_Poisson_From           matlab.ui.control.Spinner
        SP_Poisson_To             matlab.ui.control.Spinner
        toLabel_5                 matlab.ui.control.Label
        CB_Shear                  matlab.ui.control.CheckBox
        SP_Shear_From             matlab.ui.control.Spinner
        SP_Shear_To               matlab.ui.control.Spinner
        toLabel_6                 matlab.ui.control.Label
        CB_Conductivity           matlab.ui.control.CheckBox
        SP_Conductivity_From      matlab.ui.control.Spinner
        SP_Conductivity_To        matlab.ui.control.Spinner
        toLabel_7                 matlab.ui.control.Label
        CB_Expansion              matlab.ui.control.CheckBox
        SP_Expansion_From         matlab.ui.control.Spinner
        SP_Expansion_To           matlab.ui.control.Spinner
        toLabel_8                 matlab.ui.control.Label
        kgmLabel                  matlab.ui.control.Label
        kPaLabel                  matlab.ui.control.Label
        kPaLabel_2                matlab.ui.control.Label
        kPaLabel_3                matlab.ui.control.Label
        Label                     matlab.ui.control.Label
        kPaLabel_4                matlab.ui.control.Label
        WmKLabel                  matlab.ui.control.Label
        mmCLabel                  matlab.ui.control.Label
    end

    
    properties (Access = private)
        mainApp % Description
        matProps
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.mainApp = mainApp;
            app.matProps = mainApp.materialProperties;
            app.UITable.Data = app.matProps;
            
            app.SP_Density_From.Value = min(app.matProps.Density);
            app.SP_Density_To.Value = max(app.matProps.Density);
            
            app.SP_Yield_From.Value = min(app.matProps.YieldStrength);
            app.SP_Yield_To.Value = max(app.matProps.YieldStrength);
            
            app.SP_Tensile_From.Value = min(app.matProps.TensileStrength);
            app.SP_Tensile_To.Value = max(app.matProps.TensileStrength);
            
            app.SP_Young_From.Value = min(app.matProps.YoungModulus);
            app.SP_Young_To.Value = max(app.matProps.YoungModulus);
            
            app.SP_Poisson_From.Value = min(app.matProps.PoissonRatio);
            app.SP_Poisson_To.Value = max(app.matProps.PoissonRatio);
            
            app.SP_Shear_From.Value = min(app.matProps.ShearModulus);
            app.SP_Shear_To.Value = max(app.matProps.ShearModulus);
            
            app.SP_Conductivity_From.Value = min(app.matProps.ThermalConductivity);
            app.SP_Conductivity_To.Value = max(app.matProps.ThermalConductivity);
            
            app.SP_Expansion_From.Value = min(app.matProps.ThermalExpansionCoefficient);
            app.SP_Expansion_To.Value = max(app.matProps.ThermalExpansionCoefficient);
            
        end

        % Button pushed function: BTN_search
        function BTN_searchButtonPushed(app, event)
            MP = app.matProps;
            
            if app.CB_Density.Value
                MP = MP(MP.Density > app.SP_Density_From.Value,:);
                MP = MP(MP.Density < app.SP_Density_To.Value,:);
            end
            
            if app.CB_Yield.Value
                MP = MP(MP.YieldStrength > app.SP_Yield_From.Value,:);
                MP = MP(MP.YieldStrength < app.SP_Yield_To.Value,:);
            end
            
            if app.CB_Tensile.Value
                MP = MP(MP.TensileStrength > app.SP_Tensile_From.Value,:);
                MP = MP(MP.TensileStrength < app.SP_Tensile_To.Value,:);
            end                      
            
            if app.CB_Young.Value
                MP = MP(MP.YoungModulus > app.SP_Young_From.Value,:);
                MP = MP(MP.YoungModulus < app.SP_Young_To.Value,:);
            end
            
            if app.CB_Poisson.Value
                MP = MP(MP.PoissonRatio > app.SP_Poisson_From.Value,:);
                MP = MP(MP.PoissonRatio < app.SP_Poisson_To.Value,:);
            end
            
            if app.CB_Shear.Value
                MP = MP(MP.ShearModulus > app.SP_Shear_From.Value,:);
                MP = MP(MP.ShearModulus < app.SP_Shear_To.Value,:);
            end
            
            if app.CB_Conductivity.Value
                MP = MP(MP.ThermalConductivity > app.SP_Conductivity_From.Value,:);
                MP = MP(MP.ThermalConductivity < app.SP_Conductivity_To.Value,:);
            end
            
            if app.CB_Expansion.Value
                MP = MP(MP.ThermalExpansionCoefficient > app.SP_Expansion_From.Value,:);
                MP = MP(MP.ThermalExpansionCoefficient < app.SP_Expansion_To.Value,:);
            end
            
            app.UITable.Data = MP;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.explorematerialsUIFigure = uifigure('Visible', 'off');
            app.explorematerialsUIFigure.Name = 'explore materials';
            app.GridLayout = uigridlayout(app.explorematerialsUIFigure);
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'Material'; 'Density'; 'Yield Strength'; 'Tensile Strength'; 'Young Modulus'; 'Poisson Ratio'; 'Shear Modulus'; 'Thermal Conductivity'; 'Thermal Expansion Coefficient'; 'Group'};
            app.UITable.RowName = {};
            app.BTN_search = uibutton(app.GridLayout, 'push');
            app.BTN_search.ButtonPushedFcn = createCallbackFcn(app, @BTN_searchButtonPushed, true);
            app.BTN_search.Text = 'filter';
            app.CB_Density = uicheckbox(app.GridLayout);
            app.CB_Density.Text = 'Density';
            app.SP_Density_From = uispinner(app.GridLayout);
            app.SP_Density_To = uispinner(app.GridLayout);
            app.toLabel = uilabel(app.GridLayout);
            app.toLabel.Text = 'to';
            app.CB_Yield = uicheckbox(app.GridLayout);
            app.CB_Yield.Text = 'Yield Strength';
            app.SP_Yield_From = uispinner(app.GridLayout);
            app.SP_Yield_To = uispinner(app.GridLayout);
            app.toLabel_2 = uilabel(app.GridLayout);
            app.toLabel_2.Text = 'to';
            app.CB_Tensile = uicheckbox(app.GridLayout);
            app.CB_Tensile.Text = 'Tensile Strength';
            app.SP_Tensile_From = uispinner(app.GridLayout);
            app.SP_Tensile_To = uispinner(app.GridLayout);
            app.toLabel_3 = uilabel(app.GridLayout);
            app.toLabel_3.Text = 'to';
            app.CB_Young = uicheckbox(app.GridLayout);
            app.CB_Young.Text = 'Young Modulus';
            app.SP_Young_From = uispinner(app.GridLayout);
            app.SP_Young_To = uispinner(app.GridLayout);
            app.toLabel_4 = uilabel(app.GridLayout);
            app.toLabel_4.Text = 'to';
            app.CB_Poisson = uicheckbox(app.GridLayout);
            app.CB_Poisson.Text = 'Poisson Ratio';
            app.SP_Poisson_From = uispinner(app.GridLayout);
            app.SP_Poisson_To = uispinner(app.GridLayout);
            app.toLabel_5 = uilabel(app.GridLayout);
            app.toLabel_5.Text = 'to';
            app.CB_Shear = uicheckbox(app.GridLayout);
            app.CB_Shear.Text = 'Shear Modulus';
            app.SP_Shear_From = uispinner(app.GridLayout);
            app.SP_Shear_To = uispinner(app.GridLayout);
            app.toLabel_6 = uilabel(app.GridLayout);
            app.toLabel_6.Text = 'to';
            app.CB_Conductivity = uicheckbox(app.GridLayout);
            app.CB_Conductivity.Text = 'Thermal Conductivity';
            app.SP_Conductivity_From = uispinner(app.GridLayout);
            app.SP_Conductivity_To = uispinner(app.GridLayout);
            app.toLabel_7 = uilabel(app.GridLayout);
            app.toLabel_7.Text = 'to';
            app.CB_Expansion = uicheckbox(app.GridLayout);
            app.CB_Expansion.Text = 'Thermal Expansion Coefficient';
            app.SP_Expansion_From = uispinner(app.GridLayout);
            app.SP_Expansion_To = uispinner(app.GridLayout);
            app.toLabel_8 = uilabel(app.GridLayout);
            app.toLabel_8.Text = 'to';
            app.kgmLabel = uilabel(app.GridLayout);
            app.kgmLabel.Text = 'kg/m³';
            app.kPaLabel = uilabel(app.GridLayout);
            app.kPaLabel.Text = 'kPa';
            app.kPaLabel_2 = uilabel(app.GridLayout);
            app.kPaLabel_2.Text = 'kPa';
            app.kPaLabel_3 = uilabel(app.GridLayout);
            app.kPaLabel_3.Text = 'kPa';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '-';
            app.kPaLabel_4 = uilabel(app.GridLayout);
            app.kPaLabel_4.Text = 'kPa';
            app.WmKLabel = uilabel(app.GridLayout);
            app.WmKLabel.Text = 'W/(mK)';
            app.mmCLabel = uilabel(app.GridLayout);
            app.mmCLabel.Text = 'µm/(m°C)';
            % Show the figure after all components are created
            app.explorematerialsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_exploreMaterials(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.explorematerialsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.explorematerialsUIFigure)
        end
    end
end