classdef loops < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        loopratesUIFigure              matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        BTN_Apply                      matlab.ui.control.Button
        BTN_Cancel                     matlab.ui.control.Button
        endoflifePanel                 matlab.ui.container.Panel
        S_Recycling                    matlab.ui.control.Spinner
        recyclingrateLabel             matlab.ui.control.Label
        S_Remanufacturing              matlab.ui.control.Spinner
        remanufacturingrateLabel       matlab.ui.control.Label
        S_Refurbishment                matlab.ui.control.Spinner
        refurbishmentrateLabel         matlab.ui.control.Label
        S_Disposal                     matlab.ui.control.Spinner
        disposalrateresidualLabel      matlab.ui.control.Label
        BTN_get                        matlab.ui.control.Button
        DD_Area                        matlab.ui.control.DropDown
        Label                          matlab.ui.control.Label
        operationPanel                 matlab.ui.container.Panel
        S_Maintenance                  matlab.ui.control.Spinner
        maintenancerate1lifespanLabel  matlab.ui.control.Label
        productionPanel                matlab.ui.container.Panel
        S_RecycledMaterial             matlab.ui.control.Spinner
        recycledmaterialshareLabel     matlab.ui.control.Label
        BTN_get_2                      matlab.ui.control.Button
        DD_Area_2                      matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        mainApp % Description
        statistics
    end
    
    methods (Access = private)
        
        function setDisposal(app)
            disposalRate = round(100-app.S_Recycling.Value - app.S_Remanufacturing.Value - app.S_Refurbishment.Value,10);
            if disposalRate < 0
                app.Label.Text = "disposal rate needs to be >= 0";
                app.BTN_Apply.Enable = false;
            else
                app.S_Disposal.Value = abs(disposalRate);
                app.Label.Text = "";
                app.BTN_Apply.Enable = true;
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.S_Recycling.Value = mainApp.activeElement.rates.recycling * 100;
            app.S_Remanufacturing.Value = mainApp.activeElement.rates.remanufacturing * 100;
            app.S_Refurbishment.Value = mainApp.activeElement.rates.refurbishment * 100;
            app.S_Maintenance.Value = mainApp.activeElement.rates.maintenance;
            app.S_RecycledMaterial.Value = mainApp.activeElement.rates.recycledMaterial * 100;
            app.setDisposal();
            app.mainApp = mainApp;
            
            M = mainApp.recyclingRates;
            areas = string(M.Properties.VariableNames(2:end));
            for i = 1:length(areas)
                row = M.(areas(i));
                app.statistics(i) = row(M.materialGroup == mainApp.activeElement.materialGroup);
            end
            app.DD_Area.Items = areas;
            app.DD_Area_2.Items = areas;
        end

        % Callback function
        function S_RecyclingValueChanging(app, event)
            changingValue = event.Value;
            app.setDisposal;
        end

        % Value changed function: S_Recycling
        function S_RecyclingValueChanged(app, event)
            value = app.S_Recycling.Value;
            app.setDisposal();
        end

        % Value changed function: S_Remanufacturing
        function S_RemanufacturingValueChanged(app, event)
            value = app.S_Remanufacturing.Value;
            app.setDisposal();
        end

        % Value changed function: S_Refurbishment
        function S_RefurbishmentValueChanged(app, event)
            value = app.S_Refurbishment.Value;
            app.setDisposal();
        end

        % Button pushed function: BTN_Apply
        function BTN_ApplyButtonPushed(app, event)
            app.mainApp.activeElement.rates.disposal = app.S_Disposal.Value / 100;
            app.mainApp.activeElement.rates.refurbishment = app.S_Refurbishment.Value / 100;
            app.mainApp.activeElement.rates.remanufacturing = app.S_Remanufacturing.Value / 100;
            app.mainApp.activeElement.rates.recycling = app.S_Recycling.Value / 100;
            app.mainApp.activeElement.rates.maintenance = app.S_Maintenance.Value;
            app.mainApp.activeElement.rates.assembly = 1 - round(app.S_Refurbishment.Value / 100,8);
            app.mainApp.activeElement.rates.production = round(1 - app.S_Refurbishment.Value / 100 - app.S_Remanufacturing.Value / 100,8);
            
            %app.mainApp.activeElement.rates.virginMaterial = round(app.mainApp.activeElement.rates.production * (1 - app.S_RecycledMaterial.Value/100),8);
            app.mainApp.activeElement.rates.virginMaterial = round(1 - app.S_RecycledMaterial.Value/100,8);
            %app.mainApp.activeElement.rates.recycledMaterial = round(app.mainApp.activeElement.rates.production * app.S_RecycledMaterial.Value/100,8);
            app.mainApp.activeElement.rates.recycledMaterial= app.S_RecycledMaterial.Value/100;
            
            app.mainApp.activeElement.displayComponent(app.mainApp);
            app.delete();
        end

        % Button pushed function: BTN_Cancel
        function BTN_CancelButtonPushed(app, event)
            app.delete();
        end

        % Button pushed function: BTN_get
        function BTN_getButtonPushed(app, event)
            app.S_Recycling.Value = app.statistics(string(app.DD_Area.Value) == string(app.DD_Area.Items))*100;
        end

        % Button pushed function: BTN_get_2
        function BTN_get_2ButtonPushed(app, event)
            app.S_RecycledMaterial.Value = app.statistics(string(app.DD_Area_2.Value) == string(app.DD_Area_2.Items))*100;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.loopratesUIFigure = uifigure('Visible', 'off');
            app.loopratesUIFigure.Name = 'loop rates';
            app.GridLayout = uigridlayout(app.loopratesUIFigure);
            app.BTN_Apply = uibutton(app.GridLayout, 'push');
            app.BTN_Apply.ButtonPushedFcn = createCallbackFcn(app, @BTN_ApplyButtonPushed, true);
            app.BTN_Apply.Text = 'apply';
            app.BTN_Cancel = uibutton(app.GridLayout, 'push');
            app.BTN_Cancel.ButtonPushedFcn = createCallbackFcn(app, @BTN_CancelButtonPushed, true);
            app.BTN_Cancel.Text = 'close';
            app.endoflifePanel = uipanel(app.GridLayout);
            app.endoflifePanel.Title = 'end of life';
            app.S_Recycling = uispinner(app.endoflifePanel);
            app.S_Recycling.Step = 5;
            app.S_Recycling.Limits = [0 100];
            app.S_Recycling.ValueChangedFcn = createCallbackFcn(app, @S_RecyclingValueChanged, true);
            app.recyclingrateLabel = uilabel(app.endoflifePanel);
            app.recyclingrateLabel.Text = 'recycling rate [%]';
            app.S_Remanufacturing = uispinner(app.endoflifePanel);
            app.S_Remanufacturing.Step = 5;
            app.S_Remanufacturing.Limits = [0 100];
            app.S_Remanufacturing.ValueChangedFcn = createCallbackFcn(app, @S_RemanufacturingValueChanged, true);
            app.remanufacturingrateLabel = uilabel(app.endoflifePanel);
            app.remanufacturingrateLabel.Text = 'remanufacturing rate  [%]';
            app.S_Refurbishment = uispinner(app.endoflifePanel);
            app.S_Refurbishment.Step = 5;
            app.S_Refurbishment.Limits = [0 100];
            app.S_Refurbishment.ValueChangedFcn = createCallbackFcn(app, @S_RefurbishmentValueChanged, true);
            app.refurbishmentrateLabel = uilabel(app.endoflifePanel);
            app.refurbishmentrateLabel.Text = 'refurbishment rate [%]';
            app.S_Disposal = uispinner(app.endoflifePanel);
            app.S_Disposal.Step = 5;
            app.S_Disposal.Limits = [0 100];
            app.S_Disposal.Enable = 'off';
            app.disposalrateresidualLabel = uilabel(app.endoflifePanel);
            app.disposalrateresidualLabel.Text = 'disposal rate [%] (residual)';
            app.BTN_get = uibutton(app.endoflifePanel, 'push');
            app.BTN_get.ButtonPushedFcn = createCallbackFcn(app, @BTN_getButtonPushed, true);
            app.BTN_get.Text = 'get from statistics';
            app.DD_Area = uidropdown(app.endoflifePanel);
            app.Label = uilabel(app.endoflifePanel);
            app.Label.Text = '';
            app.operationPanel = uipanel(app.GridLayout);
            app.operationPanel.Title = 'operation';
            app.S_Maintenance = uispinner(app.operationPanel);
            app.S_Maintenance.Limits = [0 Inf];
            app.maintenancerate1lifespanLabel = uilabel(app.operationPanel);
            app.maintenancerate1lifespanLabel.Text = 'maintenance rate [1/lifespan]';
            app.productionPanel = uipanel(app.GridLayout);
            app.productionPanel.Title = 'production';
            app.S_RecycledMaterial = uispinner(app.productionPanel);
            app.S_RecycledMaterial.Step = 5;
            app.S_RecycledMaterial.Limits = [0 100];
            app.recycledmaterialshareLabel = uilabel(app.productionPanel);
            app.recycledmaterialshareLabel.Text = 'recycled material share [%]';
            app.BTN_get_2 = uibutton(app.productionPanel, 'push');
            app.BTN_get_2.ButtonPushedFcn = createCallbackFcn(app, @BTN_get_2ButtonPushed, true);
            app.BTN_get_2.Text = 'get from statistics';
            app.DD_Area_2 = uidropdown(app.productionPanel);
            % Show the figure after all components are created
            app.loopratesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = loops(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.loopratesUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.loopratesUIFigure)
        end
    end
end