classdef optionsApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LifeCycleDesignerOptionsUIFigure  matlab.ui.Figure
        displayPanel                    matlab.ui.container.Panel
        CB_Flows                        matlab.ui.control.CheckBox
        CB_normTime                     matlab.ui.control.CheckBox
        BTN_apply                       matlab.ui.control.Button
        BTN_cancel                      matlab.ui.control.Button
        lifecycleimpactassessmentPanel  matlab.ui.container.Panel
        methodLabel                     matlab.ui.control.Label
        DD_LciaMethod                   matlab.ui.control.DropDown
        CB_ObsoleteMethods              matlab.ui.control.CheckBox
        machiningallowanceLabel         matlab.ui.control.Label
        SP_Allowance                    matlab.ui.control.Spinner
        referencetimeframeyrLabel       matlab.ui.control.Label
        SP_RefTime                      matlab.ui.control.Spinner
        loopmodellingButtonGroup        matlab.ui.container.ButtonGroup
        BTN_transient                   matlab.ui.control.RadioButton
        BTN_steady                      matlab.ui.control.RadioButton
        projectPanel                    matlab.ui.container.Panel
        E_Path                          matlab.ui.control.EditField
        exchangefileLabel               matlab.ui.control.Label
        B_Path                          matlab.ui.control.Button
    end

    
    properties (Access = private)
        mainApp % Description
        options
    end
    
    methods (Access = private)
        
        function listMethods(app)
            methods = unique(app.mainApp.LCIA.method);            
            if ~app.options.showObsoleteMethods
                methods = methods(~contains(methods,'obsolete'),:);                
             end
            app.DD_LciaMethod.Items = methods;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.mainApp = mainApp;
            app.options = mainApp.options;
            app.CB_ObsoleteMethods.Value = mainApp.options.showObsoleteMethods;            
            app.listMethods();
            app.DD_LciaMethod.Value = mainApp.options.lciaMethod;            
            app.CB_Flows.Value = mainApp.options.displayFlows;
            app.SP_Allowance.Value = mainApp.options.allowance;
            app.E_Path.Value = mainApp.options.exchangeFilePath;
            app.E_Path.Value = mainApp.options.exchangeFilePath;
            app.SP_RefTime.Value = mainApp.options.referenceTime;
            app.CB_normTime.Value = mainApp.options.normTime;
            app.BTN_transient.Value = mainApp.options.transient;
            app.BTN_steady.Value = ~mainApp.options.transient;
        end

        % Button pushed function: BTN_cancel
        function BTN_cancelButtonPushed(app, event)
            app.delete();
        end

        % Button pushed function: BTN_apply
        function BTN_applyButtonPushed(app, event)
            app.options.normTime = app.CB_normTime.Value;
            app.options.referenceTime = app.SP_RefTime.Value;
            app.mainApp.options = app.options;                        
            app.mainApp.lciaMethod = app.options.lciaMethod;            
            app.mainApp.listCategories();             
            app.mainApp.L_RefTime.Text = "reference time: " + app.options.referenceTime + " yrs";                                                                       
            app.mainApp.activeElement.plot(app.mainApp);
            app.delete();            
        end

        % Value changed function: DD_LciaMethod
        function DD_LciaMethodValueChanged(app, event)
            value = app.DD_LciaMethod.Value;
            app.options.lciaMethod = value;                        
        end

        % Value changed function: CB_ObsoleteMethods
        function CB_ObsoleteMethodsValueChanged(app, event)
            value = app.CB_ObsoleteMethods.Value;            
            app.options.showObsoleteMethods = value;
            app.listMethods();
        end

        % Value changed function: CB_Flows
        function CB_FlowsValueChanged(app, event)
            value = app.CB_Flows.Value;
            app.options.displayFlows = value;
        end

        % Selection changed function: loopmodellingButtonGroup
        function loopmodellingButtonGroupSelectionChanged(app, event)
            app.options.transient = app.BTN_transient.Value;            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LifeCycleDesignerOptionsUIFigure = uifigure('Visible', 'off');
            app.LifeCycleDesignerOptionsUIFigure.Name = 'Life Cycle Designer - Options';
            app.displayPanel = uipanel(app.LifeCycleDesignerOptionsUIFigure);
            app.displayPanel.Title = 'display';
            app.CB_Flows = uicheckbox(app.displayPanel);
            app.CB_Flows.ValueChangedFcn = createCallbackFcn(app, @CB_FlowsValueChanged, true);
            app.CB_Flows.Text = 'flow boundaries';
            app.CB_normTime = uicheckbox(app.displayPanel);
            app.CB_normTime.Text = 'time-normed impacts';
            app.BTN_apply = uibutton(app.LifeCycleDesignerOptionsUIFigure, 'push');
            app.BTN_apply.ButtonPushedFcn = createCallbackFcn(app, @BTN_applyButtonPushed, true);
            app.BTN_apply.Text = 'apply';
            app.BTN_cancel = uibutton(app.LifeCycleDesignerOptionsUIFigure, 'push');
            app.BTN_cancel.ButtonPushedFcn = createCallbackFcn(app, @BTN_cancelButtonPushed, true);
            app.BTN_cancel.Text = 'cancel';
            app.lifecycleimpactassessmentPanel = uipanel(app.LifeCycleDesignerOptionsUIFigure);
            app.lifecycleimpactassessmentPanel.Title = 'life cycle impact assessment';
            app.methodLabel = uilabel(app.lifecycleimpactassessmentPanel);
            app.methodLabel.Text = 'method';
            app.DD_LciaMethod = uidropdown(app.lifecycleimpactassessmentPanel);
            app.DD_LciaMethod.ValueChangedFcn = createCallbackFcn(app, @DD_LciaMethodValueChanged, true);
            app.CB_ObsoleteMethods = uicheckbox(app.lifecycleimpactassessmentPanel);
            app.CB_ObsoleteMethods.ValueChangedFcn = createCallbackFcn(app, @CB_ObsoleteMethodsValueChanged, true);
            app.CB_ObsoleteMethods.Text = 'show obsolete methods';
            app.machiningallowanceLabel = uilabel(app.lifecycleimpactassessmentPanel);
            app.machiningallowanceLabel.Text = 'machining allowance [%]';
            app.SP_Allowance = uispinner(app.lifecycleimpactassessmentPanel);
            app.referencetimeframeyrLabel = uilabel(app.lifecycleimpactassessmentPanel);
            app.referencetimeframeyrLabel.Text = 'reference time frame [yr]';
            app.SP_RefTime = uispinner(app.lifecycleimpactassessmentPanel);
            app.SP_RefTime.Limits = [0 Inf];
            app.loopmodellingButtonGroup = uibuttongroup(app.lifecycleimpactassessmentPanel);
            app.loopmodellingButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @loopmodellingButtonGroupSelectionChanged, true);
            app.loopmodellingButtonGroup.Title = 'loop modelling';
            app.BTN_transient = uiradiobutton(app.loopmodellingButtonGroup);
            app.BTN_transient.Text = 'transient response';
            app.BTN_transient.Value = true;
            app.BTN_steady = uiradiobutton(app.loopmodellingButtonGroup);
            app.BTN_steady.Text = 'steady state';
            app.projectPanel = uipanel(app.LifeCycleDesignerOptionsUIFigure);
            app.projectPanel.Title = 'project';
            app.E_Path = uieditfield(app.projectPanel, 'text');
            app.exchangefileLabel = uilabel(app.projectPanel);
            app.exchangefileLabel.Text = 'exchange file';
            app.B_Path = uibutton(app.projectPanel, 'push');
            app.B_Path.Text = 'edit';
            % Show the figure after all components are created
            app.LifeCycleDesignerOptionsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = optionsApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LifeCycleDesignerOptionsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LifeCycleDesignerOptionsUIFigure)
        end
    end
end