classdef sensitivityApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        analyseandadviseUIFigure  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        UITable                   matlab.ui.control.Table
        TextArea                  matlab.ui.control.TextArea
        sensitivityanalysisLabel  matlab.ui.control.Label
        adviceLabel               matlab.ui.control.Label
        impactchangeon10parametervariationLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        mainApp % Description
        adviceTable
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
           
            app.mainApp = mainApp;            
            if isa(app.mainApp.activeElement,'Assembly')                                       
                resTable = asmSensitivity(app.mainApp,app.mainApp.activeElement);
            else
                [sensitivity , indiParameters] = analyseSensitivity(app.mainApp, app.mainApp.activeElement, app.mainApp.activeElement);
                componentName = strings(length(sensitivity),1);
                componentName(:) = app.mainApp.activeElement.name;
                resTable = table(componentName, indiParameters, sensitivity);                
            end
            app.adviceTable = readtable('tables/advice.xlsx');            
            app.UITable.Data = sortrows(resTable,'sensitivity','descend');
            %set(handles.app, 'Name', 'sensitivity - ' + app.mainApp.activeElement.name);
            app.analyseandadviseUIFigure.Name = 'analyse & advise - ' + app.mainApp.activeElement.name';

        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;                        
            parameter = app.UITable.Data(indices(1),2).indiParameters;   
            app.TextArea.Value = string();                       
            if sum(string(app.adviceTable.Properties.VariableNames) == parameter) ~= 0
                adviceColumn = string(app.adviceTable.(parameter));
                adviceColumn = adviceColumn(adviceColumn ~= '');                
                spacer = ['';'';''];                                               
                for i = 1:length(adviceColumn)
                    app.TextArea.Value = [app.TextArea.Value; spacer; string(i) + ') ' + string(adviceColumn(i))];
                end
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.analyseandadviseUIFigure = uifigure('Visible', 'off');
            app.analyseandadviseUIFigure.Name = 'analyse and advise';
            app.GridLayout = uigridlayout(app.analyseandadviseUIFigure);
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'component'; 'parameter'; 'sensitivity'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.TextArea = uitextarea(app.GridLayout);
            app.TextArea.Value = {'select a table entry to receive advice'};
            app.sensitivityanalysisLabel = uilabel(app.GridLayout);
            app.sensitivityanalysisLabel.Text = 'sensitivity analysis';
            app.adviceLabel = uilabel(app.GridLayout);
            app.adviceLabel.Text = 'advice';
            app.impactchangeon10parametervariationLabel = uilabel(app.GridLayout);
            app.impactchangeon10parametervariationLabel.Text = 'impact change [%] on 10 % parameter variation';
            % Show the figure after all components are created
            app.analyseandadviseUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sensitivityApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.analyseandadviseUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.analyseandadviseUIFigure)
        end
    end
end