classdef VisualizeXml < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CADLCAInterfaceUIFigure  matlab.ui.Figure
        loadxmlButton            matlab.ui.control.Button
        openxmlButton            matlab.ui.control.Button
        EditField                matlab.ui.control.EditField
        Tree                     matlab.ui.container.Tree
        UITable                  matlab.ui.control.Table
        UIAxes                   matlab.ui.control.UIAxes
        HTML                     matlab.ui.control.HTML
        Button                   matlab.ui.control.Button
        AdapterPlatePanel        matlab.ui.container.Panel
        MaterialDropDownLabel    matlab.ui.control.Label
        MaterialDropDown         matlab.ui.control.DropDown
        ProcessDropDownLabel     matlab.ui.control.Label
        ProcessDropDown          matlab.ui.control.DropDown
        defaultButton            matlab.ui.control.Button
        approveButton            matlab.ui.control.Button
        ignorecomponentCheckBox  matlab.ui.control.CheckBox
        approveallButton         matlab.ui.control.Button
    end

    
    properties (Access = private)
        fileName = 'C:\Users\Z420\Desktop\exchange_folder\F-BOM.xml'; %Xml Dir
        G = digraph %new Graph
    end
    
    methods (Access = private)        
            
        function addNode(app,root,struct)            
            if isfield(struct,"solid")
                for i = 1: length(struct.solid)
                    if length(struct.solid) == 1
                        solid = uitreenode(root,'Text',struct.solid(i).Attributes.name,'NodeData',struct.solid(i).Attributes,"icon","res\solid.png");    
                    else
                        solid = uitreenode(root,'Text',struct.solid{i}.Attributes.name,'NodeData',struct.solid{i}.Attributes,"icon","res\solid.png");                            
                    end
                end
            end
            if isfield(struct,"part")                
                for i = 1: length(struct.part)
                    if length(struct.part) == 1
                        part = uitreenode(root,'Text',struct.part(i).Attributes.name,'NodeData',struct.part(i).Attributes,"icon","res\part.png");    
                        app.addNode(part,struct.part(i));
                        app.G = addedge(app.G, struct.Attributes.name, struct.part(i).Attributes.name);
                    else
                        part = uitreenode(root,'Text',struct.part{i}.Attributes.name,'NodeData',struct.part{i}.Attributes,"icon","res\part.png");    
                        app.addNode(part,struct.part{i});
                        app.G = addedge(app.G, struct.Attributes.name, struct.part{i}.Attributes.name);
                    end                  
                end    
            end
            if isfield(struct,"CAM")
                cam = uitreenode(root,'Text','CAM','NodeData',[],"icon","res\cam.png");    
                app.addNode(cam,struct.CAM);
%                 for i = 1: length(struct.CAM)
%                     if length(struct.CAM) == 1
%                         cam = uitreenode(root,'Text','CAM','NodeData',[]);    
%                         app.addNode(cam,struct.CAM);
%                     else
%                         cam = uitreenode(root,'Text','CAM','NodeData',[]);    
%                         app.addNode(cam,struct.CAM{i});
%                     end                    
%                 end    
            end
            if isfield(struct,"setup")
                for i = 1: length(struct.setup)
                    if length(struct.setup) == 1
                        setup = uitreenode(root,'Text',struct.setup(i).Attributes.name,'NodeData',struct.setup(i).Attributes,"icon","res\setup.png");    
                        app.addNode(setup,struct.setup);
                    else
                        setup = uitreenode(root,'Text',struct.setup{i}.Attributes.name,'NodeData',struct.setup{i}.Attributes,"icon","res\setup.png");    
                        app.addNode(setup,struct.setup{i});                        
                    end                    
                end    
            end
            if isfield(struct,"operation")
                for i = 1: length(struct.operation)
                    if length(struct.operation) == 1
                        op = uitreenode(root,'Text',struct.operation(i).Attributes.name,'NodeData',struct.operation(i).Attributes,"icon","res\operation.png");    
                        app.addNode(op,struct.operation(i));
                    else
                        op = uitreenode(root,'Text',struct.operation{i}.Attributes.name,'NodeData',struct.operation{i}.Attributes,"icon","res\operation.png");    
                        app.addNode(op,struct.operation{i});
                    end                    
                end    
            end
            if isfield(struct,"model")
                for i = 1: length(struct.model)
                    if length(struct.model) == 1
                        model = uitreenode(root,'Text',struct.model(i).Attributes.name,'NodeData',struct.model(i).Attributes,"icon","res\part.png");                            
                        app.addNode(model,struct.model(i));
                    else
                        model = uitreenode(root,'Text',struct.model{i}.Attributes.name,'NodeData',struct.model{i}.Attributes,"icon","res\part.png"); 
                        app.addNode(model,struct.model{i});
                    end                    
                end    
            end
            if isfield(struct,"feature")
                for i = 1: length(struct.feature)
                    if length(struct.feature) == 1
                        feature = uitreenode(root,'Text',struct.feature(i).Attributes.name,'NodeData',struct.feature(i).Attributes,"icon","res\feature.png");                            
                        app.addNode(feature,struct.feature(i));
                    else
                        feature = uitreenode(root,'Text',struct.feature{i}.Attributes.name,'NodeData',struct.feature{i}.Attributes,"icon","res\feature.png"); 
                        app.addNode(feature,struct.feature{i});
                    end                    
                end    
            end
            
            if isfield(struct,"featureDef")
                for i = 1: length(struct.featureDef)
                    if length(struct.featureDef) == 1
                        featureDef = uitreenode(root,'Text',struct.featureDef(i).Attributes.name,'NodeData',struct.featureDef(i).Attributes,"icon","res\featureDef.png");                            
                        %app.addNode(featureDef,struct.featureDef(i));
                    else
                        featureDef = uitreenode(root,'Text',struct.featureDef{i}.Attributes.name,'NodeData',struct.featureDef{i}.Attributes,"icon","res\featureDef.png"); 
                        %app.addNode(featureDef,struct.featureDef{i});
                    end                    
                end    
            end            
            
        end       
    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.EditField.Value = app.fileName;            
            html = fileread("index.html");
            % app.HTML.HTMLSource = '<p style="transition: .3; font-family:sans-serif">This is <mark>marked</mark> text.</p>';            
            app.HTML.HTMLSource = html;

        end

        % Button pushed function: loadxmlButton
        function loadxmlButtonPushed(app, event)
            path = 'C:\Users\Z420\Desktop\exchange_folder\F-BOM.xml';
            c=xml2struct(path); 
            
            app.Tree.Children.delete;
            
            assembly = uitreenode(app.Tree,'Text',c.assembly.Attributes.name,'NodeData',c.assembly.Attributes, "Icon", "res\asm.png");
            
            asmName = c.assembly.Attributes.name;
            app.G = addnode(app.G,asmName);
            
            % recoursive calls
            app.addNode(assembly,c.assembly);
                                    
            plot(app.UIAxes,app.G,'EdgeColor','#f7b688','NodeColor','#68afe1','MarkerSize',20,'LineWidth',5,'NodeFontSize',12);
            
            
            expand(app.Tree,'all');
                        
            
        end

        % Button pushed function: openxmlButton
        function openxmlButtonPushed(app, event)
        title = 'Open XML File';
        filter = '../*.xml';       
        [file, path] = uigetfile(filter,title,app.fileName);
        if (file ~= 0)                    
            app.fileName = [path file];
            app.EditField.Value = app.fileName;
        end
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            %display(selectedNodes.NodeData);
            %app.UITable.Data = [fieldnames(selectedNodes.NodeData) struct2cell(selectedNodes.NodeData)];
            if isempty(selectedNodes.NodeData)
                app.UITable.ColumnName = [];
                app.UITable.Data = [];
            else
                app.UITable.RowName = fieldnames(selectedNodes.NodeData)';
                app.UITable.Data = struct2cell(selectedNodes.NodeData);
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.HTML.Data = 'hi';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CADLCAInterfaceUIFigure = uifigure('Visible', 'off');
            app.CADLCAInterfaceUIFigure.Name = 'CAD LCA Interface';
            app.loadxmlButton = uibutton(app.CADLCAInterfaceUIFigure, 'push');
            app.loadxmlButton.ButtonPushedFcn = createCallbackFcn(app, @loadxmlButtonPushed, true);
            app.loadxmlButton.Text = 'load xml';
            app.openxmlButton = uibutton(app.CADLCAInterfaceUIFigure, 'push');
            app.openxmlButton.ButtonPushedFcn = createCallbackFcn(app, @openxmlButtonPushed, true);
            app.openxmlButton.Text = 'open xml';
            app.EditField = uieditfield(app.CADLCAInterfaceUIFigure, 'text');
            app.Tree = uitree(app.CADLCAInterfaceUIFigure);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.UITable = uitable(app.CADLCAInterfaceUIFigure);
            app.UITable.ColumnName = {''};
            app.UITable.RowName = {};
            app.UIAxes = uiaxes(app.CADLCAInterfaceUIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YAxisLocation = 'origin';
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.HTML = uihtml(app.CADLCAInterfaceUIFigure);
            app.Button = uibutton(app.CADLCAInterfaceUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.AdapterPlatePanel = uipanel(app.CADLCAInterfaceUIFigure);
            app.AdapterPlatePanel.Title = 'Adapter Plate';
            app.MaterialDropDownLabel = uilabel(app.AdapterPlatePanel);
            app.MaterialDropDownLabel.Text = 'Material';
            app.MaterialDropDown = uidropdown(app.AdapterPlatePanel);
            app.MaterialDropDown.Items = {'Aluminium'};
            app.MaterialDropDown.Value = 'Aluminium';
            app.ProcessDropDownLabel = uilabel(app.AdapterPlatePanel);
            app.ProcessDropDownLabel.Text = 'Process';
            app.ProcessDropDown = uidropdown(app.AdapterPlatePanel);
            app.ProcessDropDown.Items = {'Casting (Aluminium)'};
            app.ProcessDropDown.Value = 'Casting (Aluminium)';
            app.defaultButton = uibutton(app.AdapterPlatePanel, 'push');
            app.defaultButton.Text = 'default';
            app.approveButton = uibutton(app.AdapterPlatePanel, 'push');
            app.approveButton.Text = 'approve';
            app.ignorecomponentCheckBox = uicheckbox(app.AdapterPlatePanel);
            app.ignorecomponentCheckBox.Text = ' ignore component';
            app.approveallButton = uibutton(app.CADLCAInterfaceUIFigure, 'push');
            app.approveallButton.Text = 'approve all';
            % Show the figure after all components are created
            app.CADLCAInterfaceUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VisualizeXml

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CADLCAInterfaceUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CADLCAInterfaceUIFigure)
        end
    end
end