classdef openEcoSpold < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EcoinventExplorerUIFigure  matlab.ui.Figure
        FileMenu                   matlab.ui.container.Menu
        saveMenu                   matlab.ui.container.Menu
        openMenu                   matlab.ui.container.Menu
        exportLCIMenu              matlab.ui.container.Menu
        EditField                  matlab.ui.control.EditField
        searchforprocessButton     matlab.ui.control.Button
        Tree                       matlab.ui.container.Tree
        UITable                    matlab.ui.control.Table
        ProcessPanel               matlab.ui.container.Panel
        UITable2                   matlab.ui.control.Table
        UITable2_2                 matlab.ui.control.Table
        TextArea                   matlab.ui.control.TextArea
        TextArea_2                 matlab.ui.control.TextArea
        GeneralCommentLabel        matlab.ui.control.Label
        ActivitiesStartLabel       matlab.ui.control.Label
        IntermediateFlowsLabel     matlab.ui.control.Label
        ElementaryFlowsLabel       matlab.ui.control.Label
        TextArea_3                 matlab.ui.control.TextArea
        ActivitiesEndLabel         matlab.ui.control.Label
        FlowPanel                  matlab.ui.container.Panel
        UITable3                   matlab.ui.control.Table
        PropertiesLabel            matlab.ui.control.Label
        UITable4                   matlab.ui.control.Table
        ProviderLabel              matlab.ui.control.Label
        setProviderButton          matlab.ui.control.Button
        Out                        matlab.ui.control.Label
        generatenetworkButton      matlab.ui.control.Button
        depthSpinnerLabel          matlab.ui.control.Label
        depthSpinner               matlab.ui.control.Spinner
        plotnetworkButton          matlab.ui.control.Button
        amountSpinnerLabel         matlab.ui.control.Label
        amountSpinner              matlab.ui.control.Spinner
        LoadLCICheckBox            matlab.ui.control.CheckBox
        includeLCICheckBox         matlab.ui.control.CheckBox
        showLCIButton              matlab.ui.control.Button
        labelsCheckBox             matlab.ui.control.CheckBox
        LabelNodeCount             matlab.ui.control.Label
        MatrixNetworkButton        matlab.ui.control.Button
        LabelMatrixNodes           matlab.ui.control.Label
        tierSpinnerLabel           matlab.ui.control.Label
        tierSpinner                matlab.ui.control.Spinner
    end

    
    properties (Access = public)
        T % lookup table containing filenames
        selectedCell % UI table selection
        startProcess = 'steel milling, small parts'  % search query
        struct % for selected process
        providerSelection  % Description
        activeNode % Selection
        lci = struct()
        lciTable = table()
        G % Graph for Tree Plot
        processTime = 0;
        nodeCount = 0;
    end
    
    methods (Access = private)
        
        function processName = loadProcess(app, fileName)                                            
                h = waitbar(0,['Load Process...']);
                if app.LoadLCICheckBox.Value
                    tmpStruct = xml2struct("Ecoinvent - cut-off system model\ecoinvent 3.6_cut-off_lci_ecoSpold02\datasets\" + fileName);                        
                else
                    tmpStruct = xml2struct("Ecoinvent - cut-off system model\ecoinvent 3.6_cut-off_ecoSpold02\datasets\" + fileName);                        
                end
                
                if isfield(tmpStruct.ecoSpold, 'activityDataset')
                    app.struct = tmpStruct.ecoSpold.activityDataset;
                else
                    app.struct = tmpStruct.ecoSpold.childActivityDataset;
                end


                processName = string(app.struct.activityDescription.activity.activityName.Text);
                app.ProcessPanel.Title = 'Process: ' + processName;


                % display comment in TextArea
                if isfield(app.struct.activityDescription.activity, 'generalComment')
                    textStruct = app.struct.activityDescription.activity.generalComment.text; 
                    if length(textStruct) > 1
                        app.TextArea.Value = textStruct{1}.Text;
                    else
                        app.TextArea.Value = textStruct.Text;
                    end                 
                end
                                
                
                % display included Activities in TextArea
                if (isfield(app.struct.activityDescription.activity,'includedActivitiesStart'))
                    app.TextArea_2.Value = app.struct.activityDescription.activity.includedActivitiesStart.Text;                    
                end 
                if (isfield(app.struct.activityDescription.activity,'includedActivitiesEnd'))
                    app.TextArea_3.Value = app.struct.activityDescription.activity.includedActivitiesEnd.Text;                   
                end
                
                waitbar(0.2,h,['Load Intermediate Exchanges...']);
                % get exchanges and display in UITable2
                exchanges = app.struct.flowData.intermediateExchange';
                exchangeNames = strings(length(exchanges),1);                
                exchangeAmount = strings(length(exchanges),1);
                exchangeUnit = strings(length(exchanges),1);
                exchangeTypes = strings(length(exchanges),1);
                exchangeGroup = strings(length(exchanges),1);
                exchangeId = strings(length(exchanges),1);
                
                if length(exchanges) == 1                    
                   exchangeNames = {exchanges.name.Text};
                   exchangeAmount = {exchanges.Attributes.amount};
                   exchangeUnit = {exchanges.unitName.Text};
                   exchangeId = {exchanges.Attributes.intermediateExchangeId};
                   if isfield(exchanges,'inputGroup') % input                                               
                       exchangeGroup = ['Input ' exchanges.inputGroup.Text];
                       if exchanges.inputGroup.Text == '5'
                           % get 'By-product classification'
                           indexBpc = 0;
                           for j=1:length(exchanges.classification)
                               if string(exchanges.classification{j}.classificationSystem.Text) == 'By-product classification'
                                   indexBpc = j;
                               end
                               if indexBpc > 0
                                   if string(exchanges.classification{indexBpc}.classificationValue.Text) == 'allocatable product'
                                       exchangeTypes = 'From Technosphere | allocatable product';
                                   elseif string(exchanges.classification{indexBpc}.classificationValue.Text) == 'Waste'                                   
                                       exchangeTypes = 'From Technosphere | waste';
                                   elseif string(exchanges.classification{indexBpc}.classificationValue.Text) == 'Recyclable'                                   
                                       exchangeTypes = 'From Technosphere | Recyclable';
                                   end
                               else
                                   exchangeTypes = 'error';
                               end                           
                           end
                       else
                           exchangeTypes = '???';
                       end                       
                   else % output
                       if exchanges.outputGroup.Text == '0'
                           exchangeTypes = 'ReferenceProduct, Output';
                       else
                           exchangeTypes = 'By-Product/Waste, Output';
                       end
                       exchangeGroup = ['Output ' exchanges.outputGroup.Text];
                   end
                   exchangeGroup = {exchangeGroup};
                   exchangeTypes = {exchangeTypes};                   
                   
                else                
                    for i=1:length(exchanges)
                       exchangeNames(i) = exchanges{i}.name.Text;
                       exchangeAmount(i) = exchanges{i}.Attributes.amount;
                       exchangeUnit(i) = exchanges{i}.unitName.Text;                       
                       exchangeId(i) = {exchanges{i}.Attributes.intermediateExchangeId};
                       if isfield(exchanges{i},'inputGroup') % input                                               
                           exchangeGroup(i) = ['Input ' exchanges{i}.inputGroup.Text];
                           if exchanges{i}.inputGroup.Text == '5'
                               % get 'By-product classification'
                               indexBpc = 0;
                               for j=1:length(exchanges{i}.classification)
                                   if string(exchanges{i}.classification{j}.classificationSystem.Text) == 'By-product classification'
                                       indexBpc = j;
                                   end
                                   if indexBpc > 0
                                       if string(exchanges{i}.classification{indexBpc}.classificationValue.Text) == 'allocatable product'
                                           exchangeTypes(i) = 'From Technosphere | allocatable product';
                                       elseif string(exchanges{i}.classification{indexBpc}.classificationValue.Text) == 'Waste'                                   
                                           exchangeTypes(i) = 'From Technosphere | waste';
                                       elseif string(exchanges{i}.classification{indexBpc}.classificationValue.Text) == 'Recyclable'                                   
                                           exchangeTypes(i) = 'From Technosphere | Recyclable';
                                       end
                                   else
                                       exchangeTypes(i) = 'error';
                                   end                           
                               end
                           else
                               exchangeTypes(i) = '???';
                           end                       
                       else % output
                           if exchanges{i}.outputGroup.Text == '0'
                               exchangeTypes(i) = 'ReferenceProduct, Output';
                           else
                               exchangeTypes(i) = 'By-Product/Waste, Output';
                           end
                           exchangeGroup(i) = ['Output ' exchanges{i}.outputGroup.Text];
                       end                               
                    end
                end
                app.UITable2.ColumnName = [{'ID'}, {'FlowName'}, {'Amount'}, {'Unit'}, {'FlowType'},{'Group'}];
                app.UITable2_2.ColumnName = app.UITable2.ColumnName;
                FlowTable = table(exchangeId,exchangeNames,exchangeAmount,exchangeUnit,exchangeTypes,exchangeGroup);
                %FlowTable = sortrows(FlowTable,4,"ascend"); 
                app.UITable2.Data = FlowTable;                
                
                waitbar(0.6,h,['Load Elementary Flows...']);
                % get elementary flows and display in UITable2_2
                if isfield(app.struct.flowData, 'elementaryExchange')
                    eflows = app.struct.flowData.elementaryExchange';
                    eflowId = strings(length(eflows ),1);
                    eflowNames = strings(length(eflows ),1);                
                    eflowAmount = strings(length(eflows ),1);
                    eflowUnit = strings(length(eflows ),1);
                    eflowType = strings(length(eflows ),1);
                    eflowGroup = strings(length(eflows ),1);
                    
                    if length(eflows) > 1
                        for i = 1:length(eflows)
                            eflowId(i) = {string(eflows{i}.Attributes.elementaryExchangeId)};
                            eflowNames(i) = string(eflows{i}.name.Text);                                    
                            eflowAmount(i) = string(eflows{i}.Attributes.amount);
                            eflowUnit(i) = string(eflows{i}.unitName.Text);
                            subcomp = eflows{i}.compartment.compartment.Text;
                            if isfield(eflows{i}, 'inputGroup')
                                eflowType(i) = ['From Environment | ' subcomp];
                                eflowGroup(i) = ['Input ' eflows{i}.inputGroup.Text];
                            else                                
                                eflowType(i) = ['To Environment | ' subcomp];
                                eflowGroup(i) = ['Output ' eflows{i}.outputGroup.Text];
                            end
                            
                        end
                    else
                        eflowId = {string(eflows.Attributes.elementaryExchangeId)};
                        eflowNames = {string(eflows.name.Text)};
                        eflowAmount = {string(eflows.Attributes.amount)};
                        eflowUnit = {string(eflows.unitName.Text)};                        
                        subcomp = eflows.compartment.compartment.Text;
                        if isfield(eflows, 'inputGroup')
                                eflowType = {['From Environment | ' subcomp]};
                                eflowGroup = {['Input' eflows.inputGroup.Text]};
                        else                                                                
                                eflowType = {['To Environment | ' subcomp]};
                                eflowGroup = {['Output' eflows.outputGroup.Text]};                                
                        end
                        
                    end                                         
                    
                else
                   eflowId = cell(0);
                   eflowNames = cell(0);
                   eflowAmount = cell(0);
                   eflowUnit = cell(0);
                   eflowType = cell(0);
                   eflowGroup = cell(0);
                end
                
                waitbar(0.9,h,['Display Flows...']);
                FlowTable = table(eflowId,eflowNames,eflowAmount,eflowUnit,eflowType,eflowGroup);
                %FlowTable = sortrows(FlowTable,1,"ascend"); 
                app.UITable2_2.Data = FlowTable;
                close(h)                   
        end
        
        function getFlowByName(app, name)            
            exFlows = app.struct.flowData.intermediateExchange';            
            app.FlowPanel.Title = 'Flow: ' + string(name);                          
            exFlow = struct();                       
            
            if length(exFlows) == 1
                if string(exFlows.name.Text) == name
                  exFlow = exFlows;
                end
            else
                for i=1:length(exFlows)
                    if string(exFlows{i}.name.Text) == name                        
                        exFlow = exFlows{i};                                
                        break
                    end
                end                             
            end
            
            if  ~isempty(exFlow)
                % get Provider
                provId = exFlow.Attributes.intermediateExchangeId;                
                providerTable = app.T(find(contains(app.T.Filename,provId)),1:2);                
                app.UITable4.ColumnName = providerTable.Properties.VariableNames;
                app.UITable4.Data = providerTable;                                
                
                FlowNames = strings(0);
                if isfield(exFlow, 'property')
                    props = exFlow.property;                
                    FlowNames = strings(length(props),1);
                    if length(props) == 1
                        FlowNames = props.name.Text;
                    else
                    for e=1:length(props)
                        FlowNames(e) = props{e}.name.Text;                        
                    end                    
                    end
                else                    
                    
                end 
                FlowNames = {FlowNames};
                exFlowTable = cell2table(FlowNames);
                app.UITable3.Data = exFlowTable;                    
            else
                
            end            
        end
        
        function addProvider(app,id, node)
            provStruct = xml2struct("Ecoinvent - cut-off system model\ecoinvent 3.6_cut-off_ecoSpold02\datasets\" + id);
            provider = uitreenode(node,'Text',processName,'NodeData',app.struct, "Icon", "res\asm.png");
        end
        
        function generateProvider(app,ProcessFile,node,depth)            
            if depth == 1
                h = waitbar(0,['Calculating Network...']);
            end
            processStruct = xml2struct("Ecoinvent - cut-off system model\ecoinvent 3.6_cut-off_ecoSpold02\datasets\" + ProcessFile);            
            
            % get exchanges and call recursive
            if ~isfield(processStruct.ecoSpold,'childActivityDataset')
                return
            end            
            exchanges = processStruct.ecoSpold.childActivityDataset.flowData.intermediateExchange';
            
            if length(exchanges) == 1                 
                if (abs(str2double(exchanges.Attributes.amount))*node.NodeData.amount > app.amountSpinner.Value)
                    
                   % get 'By-product classification'
                   indexBpc = 0;
                   for j=1:length(exchanges.classification)
                       if string(exchanges.classification{j}.classificationSystem.Text) == 'By-product classification'
                           indexBpc = j; 
                           break; 
                       end
                   end                       
                   if indexBpc > 0
                       if string(exchanges.classification{indexBpc}.classificationValue.Text) == 'allocatable product'
                           app.linkProvider(exchanges, node, depth);                        
                       elseif string(exchanges.classification{indexBpc}.classificationValue.Text) == 'Waste'                                                                  
                           app.linkProvider(exchanges, node, depth);                        
                       elseif string(exchanges.classification{indexBpc}.classificationValue.Text) == 'Recyclable'
                           % 'Recyclable - nothing to do here';                               
                       end
                   else
                       disp('classification error for ' + exchanges.name.Text);
                   end                                                                                                 
                end                                
            else            
                for i=1:length(exchanges)
                    if depth == 1
                        waitbar(i / length(exchanges))
                    end                    
                    if (abs(str2double(exchanges{i}.Attributes.amount))*node.NodeData.amount > app.amountSpinner.Value)
                        
                       % get 'By-product classification'
                       indexBpc = 0;
                       for j=1:length(exchanges{i}.classification)
                           if string(exchanges{i}.classification{j}.classificationSystem.Text) == 'By-product classification'
                               indexBpc = j; 
                               break; 
                           end
                       end                       
                       if indexBpc > 0
                           if string(exchanges{i}.classification{indexBpc}.classificationValue.Text) == 'allocatable product'
                               app.linkProvider(exchanges{i}, node, depth);                        
                           elseif string(exchanges{i}.classification{indexBpc}.classificationValue.Text) == 'Waste'                                                                  
                               app.linkProvider(exchanges{i}, node, depth);                        
                           elseif string(exchanges{i}.classification{indexBpc}.classificationValue.Text) == 'Recyclable'
                               % 'Recyclable - nothing to do here';   
                           else
                               % e.g. Outputs
                           end
                       else
                           disp('classification error for ' + exchanges{i}.name.Text);
                       end                                                                                                 
                    end            
                end
            end                                                                              
            
            if depth == 1
                close(h)                
            end
        end
        
        function createLciTable(app)
            IDs = fieldnames(app.lci);
            
            id = {};
            name = {};
            amount = {};
            type = {};
            uid = {};
            
            t = table(id, name, amount, type, uid);                       
            for i = 1:length(IDs)
                uid = string(IDs{i});
                id = string(app.lci.(IDs{i}).id);
                name = string(app.lci.(IDs{i}).name);
                amount = string(app.lci.(IDs{i}).amount);
                type = string(app.lci.(IDs{i}).type);
                t = [t;{id, name, amount, type, uid}];
            end
            
            t= sortrows(t,2,"ascend"); 
            app.lciTable = t;
        end
        
        function  getLci(app,providerFile)
            %get elementary flows for lci            
            if app.includeLCICheckBox.Value        
                provStruct = xml2struct("Ecoinvent - cut-off system model\ecoinvent 3.6_cut-off_ecoSpold02\datasets\" + providerFile);            
                if ~isfield(provStruct.ecoSpold,'childActivityDataset')
                    return
                end
                if isfield(provStruct.ecoSpold.childActivityDataset.flowData, 'elementaryExchange')                                        
                    elExchanges = provStruct.ecoSpold.childActivityDataset.flowData.elementaryExchange';                                                       
                    if length(elExchanges) == 1                                                
                        oId = elExchanges.Attributes.elementaryExchangeId;
                        if isfield(elExchanges, 'inputGroup')
                            elId = "in_" + strrep(oId, '-','_');
                            type = 'input';
                        else
                            elId = "out_" + strrep(oId, '-','_');
                            type = 'output';
                        end                                         
                        elName = elExchanges.name.Text;
                        elAmount = str2double(string(elExchanges.Attributes.amount)); %abs entfernt
                        if isfield(app.lci, elId)
                            newAmount = string(str2double(app.lci.(elId).amount) + elAmount);
                            app.lci.(elId).amount = newAmount;
                        else                            
                            app.lci.(elId).name = elName;
                            app.lci.(elId).amount = string(elAmount);
                            app.lci.(elId).id = oId;
                            app.lci.(elId).type = type;
                            
                        end
                    else
                        for e=1:length(elExchanges)
                            oId = elExchanges{e}.Attributes.elementaryExchangeId;
                            if isfield(elExchanges{e}, 'inputGroup')
                               elId = "in_" + strrep(oId,'-','_');               
                               type = 'input';
                            else
                               elId = "out_" + strrep(oId,'-','_');
                               type = 'output';
                            end
                            elName = elExchanges{e}.name.Text;
                            elAmount = str2double(string(elExchanges{e}.Attributes.amount)); %abs entfernt
                            if isfield(app.lci, elId)
                                newAmount = string(str2double(app.lci.(elId).amount) + elAmount);
                                app.lci.(elId).amount = newAmount;                                
                            else
                                app.lci.(elId).id = oId;
                                app.lci.(elId).name = elName;
                                app.lci.(elId).amount = string(elAmount);
                                app.lci.(elId).type = type;
                            end
                        end                
                    end    
                else
                    
                end
            end            
        end       

        
        function linkProvider(app, exchange, node, depth)
            if isfield(exchange.Attributes , 'activityLinkId')
                provId = exchange.Attributes.activityLinkId;
            else
                %provId = exchange.Attributes.intermediateExchangeId;
                %processName = processStruct.ecoSpold.childActivityDataset.activityDescription.activity.activityName.Text;
                %loc = processStruct.ecoSpold.childActivityDataset.activityDescription.geography.shortname.Text;
                %disp('No activityLinkId for <' + string(exchange.name.Text) + '> in process <' + processName + ', ' + loc+ '>');
                disp('No activityLinkId for <' + string(exchange.name.Text));
                return
            end
            
            %if isfield(exchanges,'inputGroup') && (str2double(exchanges.Attributes.amount)*node.NodeData.amount > app.amountSpinner.Value)
                               
            providerTable = app.T(find(contains(app.T.Filename,provId)),1:2);
            
            providerName = string(providerTable{1,2});
            providerFile = providerTable{1,1};
            provNode.file = providerFile;
            exchangeAmount = str2double(exchange.Attributes.amount);
            provNode.amount = node.NodeData.amount * exchangeAmount;
            providerNode = uitreenode(node,'Text',providerName,'NodeData',provNode, "Icon", "res\part.png");
            app.nodeCount = app.nodeCount+1;
            
            Weight = abs(provNode.amount);
            maxDepth = app.depthSpinner.Value;
            edgeColor = [(depth-1)/maxDepth 0 1-(depth-1)/maxDepth];
            edgeName = string(exchange.name.Text) + ' [' + string(provNode.amount) + ']';
            edgeTable = table(Weight,edgeColor,edgeName);                                        
            app.G = addedge(app.G, node.Text, providerName, edgeTable);                                                           
            if depth < app.depthSpinner.Value
                app.generateProvider(providerFile,providerNode,depth+1);
            end 
            app.getLci(providerFile);
        end
        
        function matrixLink(app)
            %preparation
            
            activityName = 'steel milling, small parts';
            activityLoc = 'RoW';
            
            path = 'Ecoinvent - cut-off system model\universal_matrix_export_3.6_cut-off\';
            A_raw = readtable([path 'A_public.csv']);
            A = sparse(A_raw.row+1,A_raw.column+1,A_raw.coefficient);
            ie = readtable([path 'ie_index.csv']);
            n = length(A);            
            activityTable = ie(find(contains(ie.activityName,activityName)),:); %get index for activity            
            indexTable = activityTable(find(contains(activityTable.geography,activityLoc)),5);
            index = indexTable.index;                       
            
            D = digraph();
            OccID = 1;
            Name = string(OccID);
            Title = ie.activityName(index);
            Amount = 1;
            nodeTable = table(Name, Title, Amount );
            D = addnode(D,nodeTable);

            maxTier = app.tierSpinner.Value;
            matrixExpand(index+1,1,1,OccID,1);

            app.LabelMatrixNodes.Text = 'Nodes: ' + string(length(D.Nodes.Name));
            plot(D,'NodeLabel',D.Nodes.Title,'Layout',"layered");

            function matrixExpand(r,v,totalAmount,parent,tier)
                disp('tier: '+string(tier))
                fFull = zeros(n,1);                
                fFull(r) = v;
                if(tier < maxTier)
                    occ = sparse(A*fFull);
                    [i,~,s] = find(occ); % return indices                    
                    for m=1:length(i)                                                                            
                        Amount = s(m);
                        if ismissing(Amount)
                            Amount = 0;                                       
                        end
                        disp(string(Amount))
                        %disp(string(Amount) + ' x ' + string(totalAmount) + ' = ' + string(Amount*totalAmount))
                        if abs(Amount*totalAmount) > 0.5                            
                            OccID = OccID + 1;                                                                                                                        
                            Name = string(OccID);
                            Title = string(ie.activityName(i(m))) + ' <' + string(Amount*totalAmount) + '>' ;
                            nodeTable = table(Name, Title, Amount);
                            D = addnode(D, nodeTable);
                            D = addedge(D,string(parent),string(OccID));
                            matrixExpand(i,v,Amount*totalAmount,OccID,tier+1);
                        end
                    end                    
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.T = readtable('Ecoinvent - cut-off system model\ecoinvent 3.6_cut-off_ecoSpold02\FilenameToActivtiyLookup.csv','Format','%s %s %s %s');            
            app.UITable.ColumnName = app.T.Properties.VariableNames;
            
            app.EditField.Value = app.startProcess;
            app.searchforprocessButtonPushed(); % automatically click load button
            
                        
        end

        % Button pushed function: searchforprocessButton
        function searchforprocessButtonPushed(app, event)
            searchString = app.EditField.Value;            
            Occ = app.T(find(contains(app.T.ActivityName,searchString)),:);
            app.UITable.Data = Occ;            
            
        end

        % Callback function
        function loadprocessButtonPushed(app, event)
            if ~isempty(app.selectedCell)                                                               
                %Add selected process name to panel                                
                filename = string(app.UITable.Data{app.selectedCell(1),1}); % get filename for selected cell in UITable                            
                app.loadProcess(filename)
            end 
            
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
           app.selectedCell = event.Indices;
           if ~isempty(app.selectedCell)                                                               
                %Add selected process name to panel
                filename = string(app.UITable.Data{app.selectedCell(1),1}); % get filename for selected cell in UITable            
                processName = app.loadProcess(filename);
                app.Tree.Children.delete;            
                provNode.file = filename;
                provNode.amount = 1; %Reference Product flow
                mainprocess = uitreenode(app.Tree,'Text',processName,'NodeData',provNode, "Icon", "res\part.png");
                app.activeNode = mainprocess;
           end                     
           
        end

        % Cell selection callback: UITable2
        function UITable2CellSelection(app, event)
            indices = event.Indices;
            flowName = app.UITable2.Data{indices(1),2};
            app.getFlowByName(flowName);
        end

        % Button pushed function: setProviderButton
        function setProviderButtonPushed(app, event)
            ProviderName = string(app.UITable4.Data{app.providerSelection(1),2});            
            ProviderFile = string(app.UITable4.Data{app.providerSelection(1),1});       
            provNode.file = ProviderFile;
            provNode.amount = 0;
            providerNode = uitreenode(app.activeNode,'Text',ProviderName,'NodeData',provNode, "Icon", "res\asm.png");            
            expand(app.Tree,'all');
            
        end

        % Cell selection callback: UITable4
        function UITable4CellSelection(app, event)
            app.providerSelection = event.Indices;
            
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            app.activeNode = selectedNodes;
            if ~isempty(selectedNodes)                
                processFile = string(selectedNodes.NodeData.file);
                app.loadProcess(processFile);
            end
            
        end

        % Button pushed function: generatenetworkButton
        function generatenetworkButtonPushed(app, event)

            if ~isempty(app.activeNode)
                tic;
                app.G = digraph();
                app.G = addnode(app.G,app.activeNode.Text);
                
                app.nodeCount = 1;
                
                provNodeText = app.activeNode.Text;
                provNode.file = app.activeNode.NodeData.file;
                provNode.amount = 1; %Reference Product flow
                
                app.Tree.Children.delete;                            
                mainprocess = uitreenode(app.Tree,'Text',provNodeText ,'NodeData',provNode, "Icon", "res\part.png");
                app.activeNode = mainprocess;
                
                app.getLci(app.activeNode.NodeData.file);
                app.generateProvider(app.activeNode.NodeData.file, app.activeNode, 1);
                expand(app.Tree,'all');   
                
                app.createLciTable()
                app.processTime = toc;       
                
                app.LabelNodeCount.Text = "Nodes: " + string(app.nodeCount);
            else
                f = msgbox('nothing selected');
            end
        end

        % Button pushed function: plotnetworkButton
        function plotnetworkButtonPushed(app, event)
            %plot(app.G,'Layout','force3','NodeLabel',app.G.Nodes.Name);
            %expTree = app.G; save('Tree.mat','app.G')                      
            plotFig = figure('Name','Process Network','NumberTitle','off'); 
            if app.labelsCheckBox.Value
                plot(app.G,'Layout','force','NodeLabel',app.G.Nodes.Name,'LineWidth',app.G.Edges.Weight,'EdgeColor', app.G.Edges.edgeColor,'EdgeLabel', app.G.Edges.edgeName, 'EdgeLabelColor', app.G.Edges.edgeColor);
            else
                plot(app.G,'Layout','force','NodeLabel',{},'LineWidth',app.G.Edges.Weight,'EdgeColor', app.G.Edges.edgeColor,'EdgeLabel',{}, 'EdgeLabelColor', app.G.Edges.edgeColor);
            end
        end

        % Button pushed function: showLCIButton
        function showLCIButtonPushed(app, event)
            fig = uifigure('Name','LCI','NumberTitle','off'); 
            uit = uitable(fig,'Data',app.lciTable);
            uit.Position = [20 20 520 380];
        end

        % Value changed function: LoadLCICheckBox
        function LoadLCICheckBoxValueChanged(app, event)
           value = app.LoadLCICheckBox.Value;           
           if ~isempty(app.selectedCell) && ~isempty(app.UITable.Data)
                %Add selected process name to panel
                filename = string(app.UITable.Data{app.selectedCell(1),1}); % get filename for selected cell in UITable            
                processName = app.loadProcess(filename);
                app.Tree.Children.delete;            
                provNode.file = filename;
                provNode.amount = 1; %Reference Product flow
                mainprocess = uitreenode(app.Tree,'Text',processName,'NodeData',provNode, "Icon", "res\part.png");
                app.activeNode = mainprocess;
           end   
        end

        % Menu selected function: saveMenu
        function saveMenuSelected(app, event)
            [file,path] = uiputfile('*.mat');
            drawnow;
            figure(app.EcoinventExplorerUIFigure)
            if ~isempty(file)
                lci_table_b = app.lciTable;
                lci_b = app.lci;
                G_b = app.G;
                nodes_b = app.Tree.Children;
                time = app.processTime;       
                nCount = app.nodeCount;
                save(fullfile(path,file),'lci_b','lci_table_b','G_b','nodes_b','time','nCount');
                drawnow;
                figure(app.EcoinventExplorerUIFigure)
                msgbox('saved to ' + string(file));
            end
        end

        % Menu selected function: openMenu
        function openMenuSelected(app, event)
            [file,path] = uigetfile('*.mat');
            drawnow;
            figure(app.EcoinventExplorerUIFigure)
            if ~isempty(file)            
                h = waitbar(0,['Load Process...']);
                delete(app.Tree.Children);             
                load(fullfile(path,file),'lci_b','lci_table_b','G_b','nodes_b','time','nCount');
                app.nodeCount = nCount;
                app.nodeCount = nCount;
                waitbar(50,h,['Integrate Data...'])
                nodes_b.Parent=app.Tree;            
                app.processTime = time;
                app.lciTable = lci_table_b;
                expand(app.Tree,'all');
                app.G = G_b;
                app.lci = lci_b;
                close(h)
                drawnow;
                figure(app.EcoinventExplorerUIFigure)
                msgbox('opened ' + string(file));   
            end
        end

        % Menu selected function: exportLCIMenu
        function exportLCIMenuSelected(app, event)
            if ~isempty(app.activeNode)
                [file,path] = uiputfile('*.mat');
                drawnow;
                figure(app.EcoinventExplorerUIFigure)                
                if ~isempty(file)
                    exTable = app.UITable2_2.Data;   
                    uid = strings(length(exTable.eflowId),1);
                    type = strings(length(exTable.eflowId),1);
                    for i = 1:length(exTable.eflowId)              
                        oId = exTable.eflowId{i};
                        if (string(exTable.eflowType) == 'Input 4')                            
                            uid(i) = "in_" + strrep(oId,'-','_');               
                            type(i) = 'input';
                        else
                            uid(i) = "out_" + strrep(oId,'-','_');               
                            type(i) = 'output';
                        end
                    end
                    id = exTable.eflowId;
                    name = exTable.eflowNames;
                    amount = exTable.eflowAmount;                    
                    
                    lci_table_b = table(id,name,amount,type,uid);
                    
                    save(fullfile(path,file),'lci_table_b');
                    drawnow;
                    figure(app.EcoinventExplorerUIFigure)
                    msgbox('LCI saved to ' + string(file));
                end
            end
        end

        % Button pushed function: MatrixNetworkButton
        function MatrixNetworkButtonPushed(app, event)
            app.matrixLink();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EcoinventExplorerUIFigure = uifigure('Visible', 'off');
            app.EcoinventExplorerUIFigure.Name = 'Ecoinvent Explorer';
            app.FileMenu = uimenu(app.EcoinventExplorerUIFigure);
            app.FileMenu.Text = 'File';
            app.saveMenu = uimenu(app.FileMenu);
            app.saveMenu.MenuSelectedFcn = createCallbackFcn(app, @saveMenuSelected, true);
            app.saveMenu.Text = 'save';
            app.openMenu = uimenu(app.FileMenu);
            app.openMenu.MenuSelectedFcn = createCallbackFcn(app, @openMenuSelected, true);
            app.openMenu.Text = 'open';
            app.exportLCIMenu = uimenu(app.FileMenu);
            app.exportLCIMenu.MenuSelectedFcn = createCallbackFcn(app, @exportLCIMenuSelected, true);
            app.exportLCIMenu.Text = 'export LCI';
            app.EditField = uieditfield(app.EcoinventExplorerUIFigure, 'text');
            app.searchforprocessButton = uibutton(app.EcoinventExplorerUIFigure, 'push');
            app.searchforprocessButton.ButtonPushedFcn = createCallbackFcn(app, @searchforprocessButtonPushed, true);
            app.searchforprocessButton.Text = 'search for process';
            app.Tree = uitree(app.EcoinventExplorerUIFigure);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.UITable = uitable(app.EcoinventExplorerUIFigure);
            app.UITable.ColumnName = {''};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.UITable.ColumnEditable = true;
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.ProcessPanel = uipanel(app.EcoinventExplorerUIFigure);
            app.ProcessPanel.Title = 'Process';
            app.UITable2 = uitable(app.ProcessPanel);
            app.UITable2.ColumnName = {''};
            app.UITable2.RowName = {};
            app.UITable2.ColumnSortable = true;
            app.UITable2.ColumnEditable = true;
            app.UITable2.CellSelectionCallback = createCallbackFcn(app, @UITable2CellSelection, true);
            app.UITable2_2 = uitable(app.ProcessPanel);
            app.UITable2_2.ColumnName = {''};
            app.UITable2_2.RowName = {};
            app.UITable2_2.ColumnSortable = true;
            app.UITable2_2.ColumnEditable = true;
            app.TextArea = uitextarea(app.ProcessPanel);
            app.TextArea.Editable = 'off';
            app.TextArea_2 = uitextarea(app.ProcessPanel);
            app.TextArea_2.Editable = 'off';
            app.GeneralCommentLabel = uilabel(app.ProcessPanel);
            app.GeneralCommentLabel.Text = 'General Comment';
            app.ActivitiesStartLabel = uilabel(app.ProcessPanel);
            app.ActivitiesStartLabel.Text = 'Activities Start';
            app.IntermediateFlowsLabel = uilabel(app.ProcessPanel);
            app.IntermediateFlowsLabel.Text = 'Intermediate Flows';
            app.ElementaryFlowsLabel = uilabel(app.ProcessPanel);
            app.ElementaryFlowsLabel.Text = 'Elementary Flows';
            app.TextArea_3 = uitextarea(app.ProcessPanel);
            app.TextArea_3.Editable = 'off';
            app.ActivitiesEndLabel = uilabel(app.ProcessPanel);
            app.ActivitiesEndLabel.Text = 'Activities End';
            app.FlowPanel = uipanel(app.ProcessPanel);
            app.FlowPanel.Title = 'Flow';
            app.UITable3 = uitable(app.FlowPanel);
            app.UITable3.ColumnName = {''};
            app.UITable3.RowName = {};
            app.PropertiesLabel = uilabel(app.FlowPanel);
            app.PropertiesLabel.Text = 'Properties';
            app.UITable4 = uitable(app.FlowPanel);
            app.UITable4.ColumnName = {''};
            app.UITable4.RowName = {};
            app.UITable4.CellSelectionCallback = createCallbackFcn(app, @UITable4CellSelection, true);
            app.ProviderLabel = uilabel(app.FlowPanel);
            app.ProviderLabel.Text = 'Provider';
            app.setProviderButton = uibutton(app.FlowPanel, 'push');
            app.setProviderButton.ButtonPushedFcn = createCallbackFcn(app, @setProviderButtonPushed, true);
            app.setProviderButton.Text = 'setProvider';
            app.Out = uilabel(app.EcoinventExplorerUIFigure);
            app.Out.Text = '';
            app.generatenetworkButton = uibutton(app.EcoinventExplorerUIFigure, 'push');
            app.generatenetworkButton.ButtonPushedFcn = createCallbackFcn(app, @generatenetworkButtonPushed, true);
            app.generatenetworkButton.Text = 'generate network';
            app.depthSpinnerLabel = uilabel(app.EcoinventExplorerUIFigure);
            app.depthSpinnerLabel.Text = 'depth';
            app.depthSpinner = uispinner(app.EcoinventExplorerUIFigure);
            app.depthSpinner.Value = 2;
            app.plotnetworkButton = uibutton(app.EcoinventExplorerUIFigure, 'push');
            app.plotnetworkButton.ButtonPushedFcn = createCallbackFcn(app, @plotnetworkButtonPushed, true);
            app.plotnetworkButton.Text = 'plot network';
            app.amountSpinnerLabel = uilabel(app.EcoinventExplorerUIFigure);
            app.amountSpinnerLabel.Text = 'amount';
            app.amountSpinner = uispinner(app.EcoinventExplorerUIFigure);
            app.amountSpinner.Value = 0.1;
            app.LoadLCICheckBox = uicheckbox(app.EcoinventExplorerUIFigure);
            app.LoadLCICheckBox.ValueChangedFcn = createCallbackFcn(app, @LoadLCICheckBoxValueChanged, true);
            app.LoadLCICheckBox.Text = 'Load LCI';
            app.includeLCICheckBox = uicheckbox(app.EcoinventExplorerUIFigure);
            app.includeLCICheckBox.Text = 'include LCI';
            app.includeLCICheckBox.Value = true;
            app.showLCIButton = uibutton(app.EcoinventExplorerUIFigure, 'push');
            app.showLCIButton.ButtonPushedFcn = createCallbackFcn(app, @showLCIButtonPushed, true);
            app.showLCIButton.Text = 'show LCI';
            app.labelsCheckBox = uicheckbox(app.EcoinventExplorerUIFigure);
            app.labelsCheckBox.Text = 'labels';
            app.LabelNodeCount = uilabel(app.EcoinventExplorerUIFigure);
            app.LabelNodeCount.Text = '';
            app.MatrixNetworkButton = uibutton(app.EcoinventExplorerUIFigure, 'push');
            app.MatrixNetworkButton.ButtonPushedFcn = createCallbackFcn(app, @MatrixNetworkButtonPushed, true);
            app.MatrixNetworkButton.Text = 'MatrixNetwork';
            app.LabelMatrixNodes = uilabel(app.EcoinventExplorerUIFigure);
            app.LabelMatrixNodes.Text = '';
            app.tierSpinnerLabel = uilabel(app.EcoinventExplorerUIFigure);
            app.tierSpinnerLabel.Text = 'tier';
            app.tierSpinner = uispinner(app.EcoinventExplorerUIFigure);
            app.tierSpinner.Value = 2;
            % Show the figure after all components are created
            app.EcoinventExplorerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = openEcoSpold

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EcoinventExplorerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EcoinventExplorerUIFigure)
        end
    end
end